classdef appColeta_winMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid1              matlab.ui.container.GridLayout
        Grid1_Right        matlab.ui.container.GridLayout
        Button_Start       matlab.ui.control.StateButton
        SortRows           SortRows
        Table_Grid         matlab.ui.container.GridLayout
        Table_Label7       matlab.ui.control.Label
        Table_Label6       matlab.ui.control.Label
        Table_Label5       matlab.ui.control.Label
        Table_Label4       matlab.ui.control.Label
        Table_Label3       matlab.ui.control.Label
        Table_Label2       matlab.ui.control.Label
        Table_Label1       matlab.ui.control.Label
        Table              matlab.ui.control.Table
        Panel3             matlab.ui.container.Panel
        Panel3_Grid        matlab.ui.container.GridLayout
        Qtd_UnknownLabel   matlab.ui.control.Label
        Qtd_Unknown        matlab.ui.control.Label
        Panel2             matlab.ui.container.Panel
        Panel2_Grid        matlab.ui.container.GridLayout
        Qtd_UnmaskedLabel  matlab.ui.control.Label
        Qtd_Unmasked       matlab.ui.control.Label
        Panel1             matlab.ui.container.Panel
        Panel1_Grid        matlab.ui.container.GridLayout
        Qtd_Nodes          matlab.ui.control.Label
        Qtd_NodesLabel     matlab.ui.control.Label
        Subtitle           matlab.ui.control.Label
        Title              matlab.ui.control.Label
        Grid1_Left         matlab.ui.container.GridLayout
        Button_Integra     matlab.ui.control.StateButton
        Button_Log         matlab.ui.control.Button
        Button_Map         matlab.ui.control.Button
        Separator          matlab.ui.control.Image
        Button_Delete      matlab.ui.control.Button
        TreeLabel          matlab.ui.control.Label
        RadioGroupLabel    matlab.ui.control.Label
        RadioGroup         matlab.ui.container.ButtonGroup
        Option1            matlab.ui.control.RadioButton
        Option2            matlab.ui.control.RadioButton
        Tree               matlab.ui.container.CheckBoxTree
        Logo               matlab.ui.control.Image
    end

    
    properties (Access = private)

        CallingApp
        RootFolder

        ftpObj
        
        fig
        axes1

        UnMaskedWebSheet
        PersistentWebSheet
        UnMaskedWebSheetPath = 'C:\Users\eric\ANATEL\Planos de Monitoração 2021 - Documentos\PMEC\monitorGeral.xlsx'
        
        ScriptVersionSheet

        UnMaskedEmissions    = table('Size', [0 8],                                                                          ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'double',  'double', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'Description', 'TimeStamp', 'Tag'})
        PersistentEmissions  = table('Size', [0 8],                                                                          ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'double',  'double', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'Description', 'TimeStamp', 'Tag'})

        logTextArea
        
        rfeyeData                                                           % app.CallingApp.RFeyeList filtrada pelo campo ConnectFlag igual a 1.
        rfNodes                                                             % app.rfeyeData filtrada pela seleção da árvore.
        
        maskStruct                                                          % Aspectos gerais da máscara espectral.
        MaxDistance = 30;


    end
    
    
    methods (Access = private)

        function TreeBuilding(app)
            
            delete(app.Tree.Children)
            
            States = unique(app.rfeyeData.State);
            for ii = 1:height(States)
                ind = find(strcmp(app.rfeyeData.State, States{ii}));

                ParentNode = uitreenode(app.Tree, 'Text', States{ii}, ...
                                                  'NodeData', ind);

                for jj = ind'
                    ChildNode = uitreenode(ParentNode, 'Text', app.rfeyeData.ID{jj}, ...
                                                       'NodeData', jj);
                end
                expand(ParentNode)
            end
            
        end


        function maskMonitor(app, ind)
            
            app.rfNodes = app.rfeyeData(ind,:);
            Misc_integraImport(app)

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
            
            for ii = 1:height(app.rfNodes)
                set(d, 'Title', app.rfNodes.ID{ii}, 'Message', 'Em andamento tentativa de conexão FTP/SFTP ao sensor...')

                % Criação do objeto FTP/SFTP.
                Misc_ftpObject(app, ii)
                if isempty(app.ftpObj)
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                end
                
                % Download dos arquivos de script e máscara espectral e a 
                % sua leitura, possibilitando atualização das variáveis
                % app.ScriptVersionSheet (informação de uma das abas do arquivo
                % "monitorGeral.xlsx" constante no Integra) e app.maskStruct 
                % (antiga app.maskParam).
                d.Message = 'Em andamento tentativa de download dos arquivos de script e máscara espectral carregados no sensor...';
                errorFlag = maskMonitor_FileDownload(app, ii);

                if errorFlag
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                else
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [.5 .5 .5])
                end

                % Comparar app.maskStruct com as faixas de interesse (as
                % quais constam no arquivo "insterestBands.cfg", criando a 
                % lista de emissões desmascaradas.
                freqList = Misc_BandCheck(app);
                if ~isempty(freqList)
                    freqList(~freqList(:,2),:) = [];
                end

                if ~isempty(freqList)
                    app.Qtd_Nodes.Text = num2str(str2double(app.Qtd_Nodes.Text) + 1);
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, sprintf('Emissões desmascaradas (frequência em MHz e ocupação em kHz): %s', jsonencode([freqList(:,1)/1000 freqList(:,2)])))
                            
                    % Processo de truncamento das frequências centrais das
                    % emissões desmascaradas, possibilitando comparação com
                    % base legal (Mosaico, Stel e SRD).
                    d.Message =  'Em andamento consulta à base legal da Agência (Mosaico, Stel e SRD)...';
                    
                    TruncatedFreqs = Misc_TruncatedFrequency(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
                    bwTruncated    = freqList(:,2);
    
                    nowTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    indNode = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.ID{ii}));
                    for jj = 1:numel(TruncatedFreqs)
                        [~, Information, ~] = Misc_closestStation(app, TruncatedFreqs(jj), [app.rfNodes.Latitude(ii), app.rfNodes.Longitude(ii)]);
                        
                        Tag = sprintf('%s_%.0f_%.0f', app.rfNodes.ID{ii}, TruncatedFreqs(jj), bwTruncated(jj));
                        if isempty(find(strcmp(app.UnMaskedEmissions.Tag, Tag), 1))
                            app.UnMaskedEmissions(end+1, :) = {app.rfNodes.State{ii},   ...
                                                               app.rfNodes.City{ii},    ...
                                                               app.rfNodes.ID{ii},      ...
                                                               TruncatedFreqs(jj)/1000, ...
                                                               bwTruncated(jj),         ...
                                                               Information, nowTime, Tag};
                        end
    
                        app.Qtd_Unmasked.Text = num2str(str2double(app.Qtd_Unmasked.Text) + 1);
                        if isempty(Information)
                            app.Qtd_Unknown.Text = num2str(str2double(app.Qtd_Unknown.Text) + 1);
                        end
                        drawnow
    
                        if bwTruncated(jj) > 0                        
                            if isempty(indNode)
                                position = height(app.UnMaskedWebSheet) + 1;
                                maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                
                            else
                                indFreq = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.ID{ii}) & ...
                                              (abs(TruncatedFreqs(jj) - app.UnMaskedWebSheet.FreqCenter*1000) < 15), 1);
                                
                                if isempty(indFreq)
                                    position = height(app.UnMaskedWebSheet) + 1;
                                    maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                else
                                    maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), indFreq, Information, nowTime);
                                end
                            end
                        end
                    end
                    
                    % if the emission is in UnMaskedWebSheet but not in the mask... update
                    % emission "InMask" field accordingly
                    for jj = indNode'
                        indEmiss = find(TruncatedFreqs == app.UnMaskedWebSheet.FreqCenter(jj)*1000, 1);
    
                        if isempty(indEmiss)
                            app.UnMaskedWebSheet.Status(jj) = "Resolvido";
                            app.UnMaskedWebSheet.InMask(jj) = "Não";
                        end
                    end

                    app.Table.Data = app.UnMaskedEmissions(:,1:7);
                    pause(.5)
                    drawnow nocallbacks
                else
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, 'Não identificada emissão desmascarada.')
                end

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];

            % Atualização das planilhas carregadas no Integra            
            if app.Button_Integra.Value
                set('Cancelable', 'off', 'Message', 'Em andamento tentativa de atualizar planilha carregada no Integra...')

                writetable(app.UnMaskedWebSheet,   app.UnMaskedWebSheetPath, 'Sheet', 'desmascarados', 'WriteVariableNames', false, 'Range', 'A2');
                writetable(app.ScriptVersionSheet, app.UnMaskedWebSheetPath, 'Sheet', 'versions',      'WriteMode', 'overwritesheet');
            end

        end


        function errorFlag = maskMonitor_FileDownload(app, ind)
            
            errorFlag = 0;

            try
                cd(app.ftpObj,   '/etc/drops/');
                mget(app.ftpObj, 'rfeyed.cfg');

                scriptFilename = sprintf('%s_script.cfg', app.rfNodes.ID{ind});
                movefile('rfeyed.cfg', fullfile(app.RootFolder, 'Temp', scriptFilename));

                [maskName, methodField] = app.CallingApp.udpLogger_ScriptRead(scriptFilename);
                for ii = 1:numel(maskName)
                    cd(app.ftpObj,   '/etc/drops/logger_masks/');
                    mget(app.ftpObj, maskName{ii});

                    maskFileName = sprintf('%s_mask.csv', app.rfNodes.ID{ind});
                    movefile(maskName{ii}, fullfile(app.RootFolder, 'Temp', maskFileName));

                    maskMonitor_FileRead(app, maskFileName)

                    % Atualizando estrutura para planilha de controle de versões com dados atuais                
                    ind1 = find(contains(app.ScriptVersionSheet.Node,        app.rfNodes.ID{ind}, 'IgnoreCase', true));
                    ind2 = find(contains(app.ScriptVersionSheet.MaskName, maskName{ii},        'IgnoreCase', true));

                    ind3 = intersect(ind1, ind2);
                    if ~isempty(ind3)
                        app.ScriptVersionSheet.ScriptName(ind3)  = methodField;
                        app.ScriptVersionSheet.MaskName(ind3)    = maskName{ii}; 
                        app.ScriptVersionSheet.DateUpdated(ind3) = datetime("now");
                        app.ScriptVersionSheet.VisitCount(ind3)  = app.ScriptVersionSheet.VisitCount(ind3) + 1;

                    else
                        app.ScriptVersionSheet.Node(end+1)      = app.rfNodes.ID{ind};
                        app.ScriptVersionSheet.ScriptName(end)  = methodField;
                        app.ScriptVersionSheet.MaskName(end)    = maskName{ii}; 
                        app.ScriptVersionSheet.DateCreated(end) = datetime("now");
                        app.ScriptVersionSheet.DateUpdated(end) = datetime("now");
                        app.ScriptVersionSheet.VisitCount(end)  = 1; 
                    end  
                end

            catch
                errorFlag = 1;
            end

        end


        function maskMonitor_FileRead(app, maskFileName)

            inData = readtable(maskFileName, 'VariableNamingRule', 'preserve');
            inData.Properties.VariableNames{1} = 'Start';
            inData.Properties.VariableNames{2} = 'Stop';

            app.maskStruct = struct('freq', [], 'FreqStart', [], 'FreqStop', []);
                     
            app.maskStruct.FreqStart = inData.Start(1);
            app.maskStruct.FreqStop  = inData.Stop(end);
             
            bw = inData.Start(2:end) - inData.Stop(1:end-1);
            app.maskStruct.freq = [(inData.Stop(1:end-1) + bw/2)  bw];

        end

        
        function maskMonitor_AddInfoToSheet(app, rfNode, frequency, bw, position, Information, nowTime)

            InitialHeight = height(app.UnMaskedWebSheet);
            
            if isempty(Information)
                RegulatoryInfo = "Não licenciado";
                Description    = "";

                if position <= InitialHeight
                    Uploaded    = "Sim";
                    Status      = app.UnMaskedWebSheet.Status(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status      = "Pendente identificação";
                    dateCreated = nowTime;                                
                end
                
            else
                RegulatoryInfo = "Licenciado";
                Description    = Information;
                Status         = "Pendente confirmação";

                if position <= InitialHeight
                    Uploaded    = app.UnMaskedWebSheet.Uploaded(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    dateCreated = nowTime;                    
                end
            end

            app.UnMaskedWebSheet(position,:) = {position,       ...
                                                rfNode,         ...
                                                frequency,      ...
                                                bw,             ...
                                                RegulatoryInfo, ...
                                                Description,    ...
                                                Status,         ...
                                                "Sim",          ...
                                                Uploaded,       ...
                                                dateCreated};

        end


        function Misc_integraImport(app)
            
            opts1 = spreadsheetImportOptions("Sheet", "desmascarados",                                                                     ...
                                             "NumVariables", 14,                                                                           ...
                                             "VariableTypes", ["int32", "string", "double", "double", "string", "string", "string"         ...
                                                               "string", "string", "datetime"],                                            ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "RegulatoryInfo", "Description", "Status" ...
                                                               "InMask", "Uploaded", "dateCreated"],                                       ...
                                             "DataRange", "A2");
            
            app.UnMaskedWebSheet = readtable(app.UnMaskedWebSheetPath, opts1);
            
            opts2 = spreadsheetImportOptions("Sheet", "persistentes",                                                                      ...
                                             "NumVariables", 13,                                                                           ...
                                             "VariableTypes", ["int32", "string", "double", "double", "string", "string", "string"         ...
                                                               "string", "datetime"],                                            ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "RegulatoryInfo", "Description", "Status" ...
                                                               "Uploaded", "dateCreated"],                                       ...
                                             "DataRange", "A2");
            
            app.PersistentWebSheet = readtable(app.UnMaskedWebSheetPath, opts2);
            
            opts3 = spreadsheetImportOptions("Sheet", "versions",                                                                            ...
                                            "NumVariables", 6,                                                                               ...
                                            "VariableTypes", ["string", "string", "string", "datetime", "datetime", "int32"],                ...
                                            "VariableNames", ["Node", "ScriptName", "MaskName", "DateCreated", "DateUpdated", "VisitCount"], ...
                                            "DataRange", "A2");
            
            app.ScriptVersionSheet = readtable(app.UnMaskedWebSheetPath, opts3);
            
        end


        function Misc_ftpObject(app, ind)

            if strcmp(app.rfNodes.ftpStatus{ind}, 'Success') || strcmp(app.rfNodes.sftpStatus{ind}, 'Success')
                IPs = {app.rfNodes.IP1{ind}, app.rfNodes.IP2{ind}};
            else
                IPs = {app.rfNodes.IP2{ind}, app.rfNodes.IP1{ind}};
            end

            if strcmp(app.rfNodes.ftpStatus{ind}, 'Success') || strcmp(app.rfNodes.ftpStatus_OpenVPN{ind}, 'Success')
                Type = {'ftp', 'sftp'}; 
            else
                Type = {'sftp', 'ftp'}; 
            end

            app.ftpObj = [];
            for ii = 1:numel(IPs)
                for jj = 1:numel(Type)
                    try
                        switch Type{jj}
                            case  'ftp'; app.ftpObj =  ftp([IPs{ii}   ':21'], 'root',             app.rfNodes.rootPass{ind});
                            case 'sftp'; app.ftpObj = sftp([IPs{ii} ':2828'], 'root', 'Password', app.rfNodes.rootPass{ind});
                        end

                        Misc_LogWrite(app, app.rfNodes.ID{ind}, sprintf('%s (%s:%.0f)', class(app.ftpObj), app.ftpObj.Host, app.ftpObj.Port))
                        return
                    catch ME
                        Misc_LogWrite(app, app.rfNodes.ID{ind}, ME.message)
                    end
                end
            end
            
        end


        function Misc_LogWrite(app, Node, msg)

            ind = find(strcmp(app.rfeyeData.ID, Node), 1);

            if isempty(app.rfeyeData.Log{ind})
                app.rfeyeData.Log{ind} = msg;
            else
                app.rfeyeData.Log{ind} = sprintf('%s\n%s', app.rfeyeData.Log{ind}, msg);
            end

        end


        function Misc_TreeNodeColor(app, Node, color)

            ind1 = [];
            ind2 = [];

            for ii = 1:numel(app.Tree.Children)
                for jj = 1:numel(app.Tree.Children(ii).Children)
                    if strcmp(app.Tree.Children(ii).Children(jj).Text, Node)
                        ind1 = ii;
                        ind2 = jj;

                        break
                    end
                end

                if ~isempty(ind1)
                    addStyle(app.Tree, uistyle('FontColor', color, 'FontWeight', 'bold'), 'node', app.Tree.Children(ind1).Children(ind2));
                    break
                end
            end            

        end

        
        function freqList = Misc_BandCheck(app)

            interestBands = readtable('interestBands.cfg', 'FileType', 'text');

            freqList = [];
            for ii = 1:height(app.maskStruct.freq)
                if ~isempty(intersect(find(interestBands.FreqStart <= app.maskStruct.freq(ii,1)), find(interestBands.FreqStop >= app.maskStruct.freq(ii,1))))
                    freqList = [freqList; app.maskStruct.freq(ii,:)];
                end           
            end
           
        end   

        
        function TruncatedPeaks = Misc_TruncatedFrequency(app, FrequencyPeaks, F0, F1)

            global peaksReference
            if isempty(peaksReference)
                Misc_FindPeaksDefaultValues(app.RootFolder)
            end
            
            TruncatedPeaks    = [];
            
            for ii = numel(FrequencyPeaks):-1:1
                ind = [];
                for jj = 1:numel(peaksReference)
                    if (peaksReference(jj).Band(1)/1000 <= FrequencyPeaks(ii)) && (peaksReference(jj).Band(2)/1000 >= FrequencyPeaks(ii))
                        ind = jj;
                        break
                    end
                end
                
                if ~isempty(ind)
                    FrequencyChannels = peaksReference(ind).Channels .* 1000;
                else
                    FrequencyChannels = single(F0:25:F1);
                end

                [~, auxIndex]        = min(abs(FrequencyChannels - FrequencyPeaks(ii)));
                TruncatedPeaks(ii,1) = FrequencyChannels(auxIndex);                
            end
            
        end
        
        
        function [Distance, Information, Station] = Misc_closestStation(app, freq, GPS)
            
            global AnatelDB
            if isempty(AnatelDB)
                Misc_DataBaseRead(app.RootFolder)
            end
            
            auxIndex = find(AnatelDB.Frequency == single(freq)/1000);
            
            Distance    = [];
            Information = '';
            Station     = [];
            
            if ~isempty(auxIndex)
                ll = 0;
                for kk = auxIndex'
                    ll = ll+1;
                    
                    auxDistance    = Misc_lldistkm(GPS, [AnatelDB.Latitude(kk), AnatelDB.Longitude(kk)]);
                    auxInformation = AnatelDB.Description{kk};
                    auxStation     = AnatelDB.Station(kk);
                    
                    if ll == 1
                        Distance    = auxDistance;
                        Information = auxInformation;
                        Station     = auxStation;
                    else
                        if auxDistance < Distance
                            Distance    = auxDistance;
                            Information = auxInformation;
                            Station     = auxStation;
                        end
                    end
                end

                if Distance > app.MaxDistance
                    Distance    = [];
                    Information = '';
                    Station     = [];
                else
                    Information = sprintf('%s (%.1f km)', Information, Distance);
                end
            end
            
        end
        

        % PENDENTE REVISAR MÓDULO FLUXO UDP
        function udpMonitor(app, ind)

            app.rfNodes = app.rfeyeData(ind,:);

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on');

            for ii = 1:height(app.rfNodes)
                set('Title', app.rfNodes.ID{ii}, 'Message', 'Em andamento tentativa de extrair dados do sensor...')

                logMsg = Fcn_udpLogger(app, app.mainUdpConfig, app.rfNodes(ii,:), app.RootFolder);
               
                Misc_LogWrite(app, app.rfNodes.ID{ii}, logMsg)

                if d.CancelRequested
                    break
                end
            end

            % Atualização das planilhas carregadas no Integra            
            if app.Button_Integra.Value
                set('Cancelable', 'off', 'Message', 'Em andamento tentativa de atualizar planilha carregada no Integra...')

                writetable(app.PersistentWebSheet, app.UnMaskedWebSheetPath, 'Sheet', 'persistentes', 'WriteVariableNames', false, 'Range', 'A2');
            end
            
        end


        function LogMsg = Fcn_udpLogger(app, IPRfeye, rootFolder)

            udpConfig = app.CallingApp.udpConfig;

        %     try
                [app.CallingApp.udpPortArray, ind] = Connection_udpLogger(app.mainUdpPortArray, 8910, udpConfig.Timeout);
                udpPort = app.CallingApp.udpPortArray{ind};
                flush(udpPort)        
                
                udpLogger_Startup(udpPort, udpConfig, IPRfeye, 8910)
                
        %     catch ME
        %         ME.message
        %         err = 'conection error';
        %         flush(udpPort)
        %         return
        %     end
            
            countBroken    = [];
            bandList       = [];
            LogMsg         = [];
            nZeroDatagrams = 0;
            
            tic
            t1 = toc;
            while size(countBroken, 2) < 3 & nZeroDatagrams < 10
                try
                    if t1 > udpConfig.ConnectionTime
                        tic
                        udpLogger_Startup(udpPort, udpConfig, IPRfeye, 8910)
            
                        if ~udpPort.NumDatagramsAvailable
                            nZeroDatagrams = nZeroDatagrams + 1;
                            error('Não foi recebido datagrama do sensor.')
                        end
                        
            
                    elseif udpPort.NumDatagramsAvailable
                        SpecInfo = read(udpPort, 1, 'uint8');
                        if ~strcmp(SpecInfo.SenderAddress, IPRfeye)
                            continue
                        end
                        
                        for ii = 1:numel(SpecInfo)
                            if numel(SpecInfo(ii).Data) < 96
                                continue
                            end
            
                            MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                            ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                            DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                            EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                            
                            if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                                switch DataType  
                                    case 42
                                        countBroken = udpLogger_Read_DataType42(SpecInfo(ii).Data, countBroken);
                                        
                                    case 67
                                        if ThreadID >= 300 && ThreadID <= 390 
                                            bandList = udpLogger_Read_DataType67(SpecInfo(ii).Data, ThreadID, bandList); 
                                        end
                                        
                                    otherwise
                                        continue
                                end    
                            end    
                        end    
                    end
                    t1 = toc;
                    pause(0.1)
                    
                catch    
                end
            end
            
            if isempty(bandList)
                flush(udpPort)
                LogMsg = 'Não foram recebidos datagramas válidos para os TheadID de interesse';
                return
            end
            
            for ind = 1:size(countBroken,1)
                indFind = find([bandList.ThreadID] == 300+(ind-1)*10);
                if ~isempty(indFind)
                    bandList(indFind).broken = countBroken(ind,:); 
                end
            end
            
            save("bandList.mat", 'bandList')
            
            % selecionar do bandlist as frequencias que estão rompendo a
            % máscara com frequência para o envio do e-mail
            
            THR1 = 30;
            THR2 = 0.5;
            indThreadID = find(arrayfun(@(S) (S.broken(end)-S.broken(1))/(numel(S.broken)-1), bandList) > THR1, 1);
            if ~isempty(indThreadID) % Testa se a máscara foi rompida a uma média maior que um threshold para todos TheadIDs
                for jj = 1:numel(indThreadID)
                    
                    %% 2. Baixar máscara do RFeye
                    % Downloading and saving current mask
                    
                    credenciais =  {'root', 'anatel152'; 
                                    'root', 'pass';
                                    'root', 'Anatel#184'};
                    
                    maskName = 'mask_80dBm.csv';
            
                    % Criação do objeto FTP/SFTP.
                    Misc_ftpObject(app, ii)
                    if isempty(app.ftpObj)
                        Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                        continue
                    end
            
                    if ~isempty(err)
                        
                        for iPass = 1: height(credenciais)
                            err1    = [];
                            try
                                ssh2_conn = ssh2_config(IPRfeye, credenciais{iPass,1}, credenciais{iPass,2}, 2828);
                                sftp_get(ssh2_conn,maskName,[],'/etc/drops/logger_masks/');
                            catch ME1
                                err1 = ME1.identifier;
                            end
                            if isempty(err1)   
                                break
                            end         
                        end
        
                        if ~isempty(err1)
                            flush(udpPort)
                            LogMsg = 'Não foi possível conexão ftp/ssh com o sensor para recuperação da máscara';
                            return
                        end
                            
                    else
                        cd(ftpObj, '/etc/drops/logger_masks/'); % Deve ser substituido pelo caminho encontrado no script
                        mget(ftpObj, maskName);  
                    end
            
                    outputFolder = fullfile(rootFolder,'recursos\rfeyeFiles'); 
                    if ~exist(outputFolder, 'dir')
                        mkdir(outputFolder);
                    end
                    
                    outputMaskFileName = sprintf('mask.csv');%, app.rfeyeData.Node(indSensorTable));
                    
                    copyfile(fullfile(rootFolder,maskName), fullfile(outputFolder, outputMaskFileName));
            
                    %% Abrindo máscara baixada
                     
                    inMask = readtable([outputFolder '\' outputMaskFileName],'VariableNamingRule' ,'preserve');
                    inMask.Properties.VariableNames{1} = 'Start';
                    inMask.Properties.VariableNames{2} = 'Stop';
                    
                    F0 = bandList(indThreadID(jj)).FreqStart/1000;
                    F1 = bandList(indThreadID(jj)).FreqStop/1000;
                    
                    mask = ones([1 bandList(indThreadID(jj)).DataPoints])*1000;
                    freq = linspace(F0, F1, bandList(indThreadID(jj)).DataPoints);
            
                    freq = Misc_TruncatedFrequency(app, freq, F0, F1);
                    
                    
                    for i = 1:size(inMask,1)
                        mask(freq>=(inMask.Start(i)+5) & freq<=(inMask.Stop(i)-5)) = inMask.Threshold(i);
                    end 
            
                    occMat = zeros([bandList(indThreadID(jj)).DataPoints size(bandList(indThreadID(jj)).Traces,2)]);
            
                    occMat(bsxfun(@gt,bandList(indThreadID(jj)).Traces, mask')) = 1;
                    
                    %% 3. Avaliar quais frequências estão rompendo a máscara com frequência
                    
                     % Encontrar as linhas do occMat em que o número de 1s é maior
                     % que THR2.
                    indFreq = find(sum(occMat,2)/size(bandList(indThreadID(jj)).Traces,2) > THR2);
                     
                    if ~isempty(indFreq)
                        TruncatedFreqs = TruncatedFrequency(freq(indFreq), F0, F1);
                        TruncatedFreqs = unique(TruncatedFreqs);
                        LogMsg = sprintf('As seguinte portadoras estão rompendo a máscara do sensor com frequência:\n');
                        for iFreq = 1:length(TruncatedFreqs)
                            LogMsg = [LogMsg sprintf('%.3f MHz\n', TruncatedFreqs(iFreq)/1000)];
                        end
                        
                    else
                        TruncatedFreqs = [];
                        LogMsg = 'Não foi detectado rompimento de máscara relevante para o sensor';
                    end
                end
                
            else
                LogMsg = 'Não foi detectado rompimento de máscara relevante para o sensor';
            end
            
        end


        function udpMonitor_udpStartup(app)
            
            msg = sprintf('open %.0f', app.udpConfig.ConnectionTime);
        
            write(app.udpPort, msg, 'uint8', app.udpLogger_IP.Value, app.udpLogger_Port.Value);
            pause(app.udpConfig.HandshakePause)
        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
 
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.rfeyeData        = app.CallingApp.RFeyeList(find(app.CallingApp.RFeyeList.ConnectFlag), :);
            app.rfeyeData.Log{1} = '';

            TreeBuilding(app)

            app.Table.Data = app.UnMaskedEmissions(:,1:7);
            RadioGroupSelectionChanged(app)

        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            
            app.CallingApp.auxWin2 = [];
            delete(app.fig)
            delete(app)
            
        end

        % Value changed function: Button_Start
        function Button_StartValueChanged(app, event)
            
            ind = [];
            for ii = 1:numel(app.Tree.CheckedNodes)
                ind = [ind; app.Tree.CheckedNodes(ii).NodeData];
            end
            ind = unique(ind);

            if isempty(ind)
                uialert(app.UIFigure, 'É preciso selecionar ao menos um sensor.', 'appColeta', 'Icon', 'warning')
                return
            end

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    maskMonitor(app, ind)

                case 'Tráfego UDP'
                    udpMonitor(app, ind)
            end
            TreeSelectionChanged(app)
                     
        end

        % Button pushed function: Button_Map
        function Button_MapPushed(app, event)
            
            obj = findobj('Type', 'Figure', 'Tag', 'rfeyeMapView');
            if isempty(obj)
                mainMonitor = get(0, 'MonitorPositions');

                [~, ind] = max(mainMonitor(:,3));
                mainMonitor = mainMonitor(ind,:);

                app.fig   = figure('Name', 'appColeta', 'NumberTitle', 'off', 'Position', [mainMonitor(1)+round((mainMonitor(3)-852)/2), mainMonitor(2)+round((mainMonitor(4)-696)/2), 852, 696],  ...
                                   'Visible', 'on', 'Color', [0.94,0.94,0.94], 'GraphicsSmoothing', 'on', 'Tag', 'rfeyeMapView',                ...
                                   'MenuBar', 'none', 'ToolBar', 'none', 'CloseRequestFcn', "delete(findobj('Type', 'Figure', 'Tag', 'rfeyeMapView'))");
                
                warning('off')
                jFrame = get(app.fig, 'javaframe');
                jIcon  = javax.swing.ImageIcon(fullfile(app.RootFolder, 'Icons', 'Anatel.png'));
                jFrame.setFigureIcon(jIcon);
                warning('on')
                
                app.axes1 = geoaxes('FontSize', 9);                
                hold(app.axes1, 'on')
                
                app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                enableDefaultInteractivity(app.axes1)
                    
                for ii = 1:height(app.rfeyeData)
                    newObj = geoscatter(app.rfeyeData.Latitude(ii), app.rfeyeData.Longitude(ii), 'Tag', app.rfeyeData.ID{ii},         ...
                                                                                                 'MarkerEdgeColor', [0.50,0.50,0.50], ...
                                                                                                 'MarkerFaceColor', [0.50,0.50,0.50]);
            
                    set(newObj.DataTipTemplate, FontName='Calibri', FontSize=10)
            
                    newObj.DataTipTemplate.DataTipRows(1).Label = '';
                    newObj.DataTipTemplate.DataTipRows(2).Label = '';
                    
                    newObj.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(app.rfeyeData.ID{ii}, 'Tag', 'auto');
                    newObj.DataTipTemplate.DataTipRows        = newObj.DataTipTemplate.DataTipRows([3 1 2]);
                end
            end

        end

        % Button pushed function: Button_Log
        function Button_LogPushed(app, event)

            ind = find(strcmp(app.rfeyeData.ID, app.Tree.SelectedNodes.Text), 1);

            if ~isempty(app.rfeyeData.Log{ind})
                uialert(app.UIFigure, app.rfeyeData.Log{ind}, app.rfeyeData.ID{ind}, 'Icon', '')
            end

        end

        % Button pushed function: Button_Delete
        function Button_DeletePushed(app, event)
            
            removeStyle(app.Tree)
            app.Tree.CheckedNodes = [];

            app.UnMaskedEmissions(:,:) = [];
            app.Table.Data             = [];

            app.Qtd_Nodes.Text    = '0';
            app.Qtd_Unmasked.Text = '0';
            app.Qtd_Unknown.Text  = '0';

        end

        % Callback function: SortRows, SortRows
        function SortRowsValueChanged(app, event)
            
            if strcmp(app.SortRows.ImageSource, 'ascend.png')
                type = 'ascend';
            else
                type = 'descend';
            end

            switch app.SortRows.Value
                case 'UF';                    app.Table.Data = sortrows(app.Table.Data, [1 2 3 4], type);
                case 'Município';             app.Table.Data = sortrows(app.Table.Data, [2 3 4], type);
                case 'Sensor';                app.Table.Data = sortrows(app.Table.Data, [3 4], type);
                case 'Frequência (MHz)';      app.Table.Data = sortrows(app.Table.Data, [4 1 2 3], type);
                case 'Largura (kHz)';         app.Table.Data = sortrows(app.Table.Data, [5 4 1 2 3], type);
                case 'Descrição';             app.Table.Data = sortrows(app.Table.Data, [6 1 2 3 4], type);
                case 'Data/hora de registro'; app.Table.Data = sortrows(app.Table.Data, [7 1 2 3 4], type);
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            ind = find(strcmp(app.rfeyeData.ID, app.Tree.SelectedNodes.Text), 1);

            if isempty(ind) || isempty(app.rfeyeData.Log{ind})
                app.Button_Log.Enable = 0;
            else
                app.Button_Log.Enable = 1;
            end            

        end

        % Selection changed function: RadioGroup
        function RadioGroupSelectionChanged(app, event)

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    app.Title.Text = 'Emissões desmascaradas';
                    app.Table.Data = app.UnMaskedEmissions(:,1:7);

                case 'Tráfego UDP'
                    app.Title.Text = 'Emissões persistentes rompendo as máscaras espectrais';
                    app.Table.Data = app.PersistentEmissions(:,1:7);
            end

            app.Qtd_Nodes.Text    = num2str(numel(unique(app.Table.Data.Node)));
            app.Qtd_Unmasked.Text = num2str(height(app.Table.Data));
            app.Qtd_Unknown.Text  = num2str(numel(find(strcmp(app.Table.Data.Description, ''))));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Monitor';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid1_Left = uigridlayout(app.Grid1);
            app.Logo = uiimage(app.Grid1_Left);
            app.Logo.ImageSource = 'Puzzle.png';
            app.Tree = uitree(app.Grid1_Left, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RadioGroup = uibuttongroup(app.Grid1_Left);
            app.RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupSelectionChanged, true);
            app.Option2 = uiradiobutton(app.RadioGroup);
            app.Option2.Text = 'Tráfego UDP';
            app.Option1 = uiradiobutton(app.RadioGroup);
            app.Option1.Text = 'Máscaras espectrais';
            app.Option1.Value = true;
            app.RadioGroupLabel = uilabel(app.Grid1_Left);
            app.RadioGroupLabel.Text = 'Tipo de monitoração:';
            app.TreeLabel = uilabel(app.Grid1_Left);
            app.TreeLabel.Text = 'Sensores:';
            app.Button_Delete = uibutton(app.Grid1_Left, 'push');
            app.Button_Delete.ButtonPushedFcn = createCallbackFcn(app, @Button_DeletePushed, true);
            app.Button_Delete.Icon = 'Check2.png';
            app.Button_Delete.Tooltip = {'Faixas de interesse'};
            app.Button_Delete.Text = '';
            app.Separator = uiimage(app.Grid1_Left);
            app.Separator.ImageSource = 'Menu_Line.png';
            app.Button_Map = uibutton(app.Grid1_Left, 'push');
            app.Button_Map.ButtonPushedFcn = createCallbackFcn(app, @Button_MapPushed, true);
            app.Button_Map.Icon = 'Map2.png';
            app.Button_Map.Text = '';
            app.Button_Log = uibutton(app.Grid1_Left, 'push');
            app.Button_Log.ButtonPushedFcn = createCallbackFcn(app, @Button_LogPushed, true);
            app.Button_Log.Icon = 'Log2.png';
            app.Button_Log.Enable = 'off';
            app.Button_Log.Tooltip = {'Log'};
            app.Button_Log.Text = '';
            app.Button_Integra = uibutton(app.Grid1_Left, 'state');
            app.Button_Integra.Enable = 'off';
            app.Button_Integra.Tooltip = {'Atualizar planilha Integra'};
            app.Button_Integra.Icon = 'Flow.png';
            app.Button_Integra.Text = '';
            app.Grid1_Right = uigridlayout(app.Grid1);
            app.Title = uilabel(app.Grid1_Right);
            app.Title.Text = 'Emissões desmascaradas';
            app.Subtitle = uilabel(app.Grid1_Right);
            app.Subtitle.Text = 'Consulta';
            app.Panel1 = uipanel(app.Grid1_Right);
            app.Panel1_Grid = uigridlayout(app.Panel1);
            app.Qtd_NodesLabel = uilabel(app.Panel1_Grid);
            app.Qtd_NodesLabel.Text = 'sensores';
            app.Qtd_Nodes = uilabel(app.Panel1_Grid);
            app.Qtd_Nodes.Text = '-';
            app.Panel2 = uipanel(app.Grid1_Right);
            app.Panel2_Grid = uigridlayout(app.Panel2);
            app.Qtd_Unmasked = uilabel(app.Panel2_Grid);
            app.Qtd_Unmasked.Text = '-';
            app.Qtd_UnmaskedLabel = uilabel(app.Panel2_Grid);
            app.Qtd_UnmaskedLabel.Text = 'emissões desmascaradas';
            app.Panel3 = uipanel(app.Grid1_Right);
            app.Panel3_Grid = uigridlayout(app.Panel3);
            app.Qtd_Unknown = uilabel(app.Panel3_Grid);
            app.Qtd_Unknown.Text = '-';
            app.Qtd_UnknownLabel = uilabel(app.Panel3_Grid);
            app.Qtd_UnknownLabel.Text = 'emissões não identificadas';
            app.Table_Grid = uigridlayout(app.Grid1_Right);
            app.Table = uitable(app.Table_Grid);
            app.Table.ColumnName = {''; ''; ''; ''; ''; ''; ''};
            app.Table.RowName = {};
            app.Table.SelectionType = 'row';
            app.Table_Label1 = uilabel(app.Table_Grid);
            app.Table_Label1.Text = '  UF';
            app.Table_Label2 = uilabel(app.Table_Grid);
            app.Table_Label2.Text = '  Município';
            app.Table_Label3 = uilabel(app.Table_Grid);
            app.Table_Label3.Text = '  Sensor';
            app.Table_Label4 = uilabel(app.Table_Grid);
            app.Table_Label4.Text = {'  Frequência'; '  (MHz)'};
            app.Table_Label5 = uilabel(app.Table_Grid);
            app.Table_Label5.Text = {'  Largura '; '  (kHz)'};
            app.Table_Label6 = uilabel(app.Table_Grid);
            app.Table_Label6.Text = '  Provável emissor';
            app.Table_Label7 = uilabel(app.Table_Grid);
            app.Table_Label7.Text = '  Data/hora de registro';
            app.SortRows = SortRows(app.Grid1_Right);
            app.SortRows.Items = {'UF', 'Município', 'Sensor', 'Frequência (MHz)', 'Largura (kHz)', 'Descrição', 'Data/hora de registro'};
            app.SortRows.ImageClickedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            app.SortRows.ValueChangedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            app.Button_Start = uibutton(app.Grid1_Right, 'state');
            app.Button_Start.ValueChangedFcn = createCallbackFcn(app, @Button_StartValueChanged, true);
            app.Button_Start.Icon = 'Media_PlayWhite.png';
            app.Button_Start.Text = 'Iniciar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta_winMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end