classdef winRFeyeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Grid1                   matlab.ui.container.GridLayout
        Panel_5                 matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        instrTag                matlab.ui.control.Label
        instrImage              matlab.ui.control.Image
        GridLayout5_2           matlab.ui.container.GridLayout
        Button_connect          matlab.ui.control.Button
        Button_export           matlab.ui.control.Button
        Button_open             matlab.ui.control.Button
        Separator2              matlab.ui.control.Image
        Button_play             matlab.ui.control.Button
        Separator1              matlab.ui.control.Image
        Button_edit             matlab.ui.control.StateButton
        Button_info             matlab.ui.control.Button
        Label2                  matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        UITable                 matlab.ui.control.Table
        Panel_4                 matlab.ui.container.Panel
        GridLayout7             matlab.ui.container.GridLayout
        Grid4                   matlab.ui.container.GridLayout
        GridLayout10            matlab.ui.container.GridLayout
        LOG                     matlab.ui.control.TextArea
        LOGLabel                matlab.ui.control.Label
        GridLayout9             matlab.ui.container.GridLayout
        Panel4                  matlab.ui.container.Panel
        Grid5                   matlab.ui.container.GridLayout
        rootPass                matlab.ui.control.HTML
        rootPassLabel           matlab.ui.control.Label
        ftpPort                 matlab.ui.control.Spinner
        PortaFTPSpinnerLabel    matlab.ui.control.Label
        sftpPort                matlab.ui.control.Spinner
        PortaSFTPSpinnerLabel   matlab.ui.control.Label
        rootLogin               matlab.ui.control.EditField
        UsurioEditFieldLabel    matlab.ui.control.Label
        Sublabel3               matlab.ui.control.Label
        Panel3                  matlab.ui.container.Panel
        Grid4_3                 matlab.ui.container.GridLayout
        webPass                 matlab.ui.control.HTML
        webPassLabel            matlab.ui.control.Label
        webLogin                matlab.ui.control.EditField
        UsurioEditFieldLabel_2  matlab.ui.control.Label
        Sublabel2_2             matlab.ui.control.Label
        GridLayout8             matlab.ui.container.GridLayout
        Panel4_2                matlab.ui.container.Panel
        Grid5_2                 matlab.ui.container.GridLayout
        Longitude               matlab.ui.control.NumericEditField
        LongitudeLabel          matlab.ui.control.Label
        Latitude                matlab.ui.control.NumericEditField
        LatitudeLabel           matlab.ui.control.Label
        Sublabel3_2             matlab.ui.control.Label
        Status                  matlab.ui.control.DropDown
        StatusLabel             matlab.ui.control.Label
        Panel2                  matlab.ui.container.Panel
        Grid3                   matlab.ui.container.GridLayout
        IP1                     matlab.ui.control.EditField
        IP1Label                matlab.ui.control.Label
        IP2                     matlab.ui.control.EditField
        IP2Label                matlab.ui.control.Label
        Sublabel1_2             matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        rfeyeList
        editedList

        editedFlag = false
        
    end
    
    methods (Access = private)

        function tempList = Fcn1_OpenFile(app, FilePath)

            [tempList, msg] = OpenFile_rfeyeList(FilePath);

            if ~isempty(msg)
                Misc_uialertCreation(app, 'warning', msg)
            end

        end


        function Fcn1_saveNewFile(app, Folder)

            save(fullfile(Folder, 'rfeyeList.cfg'), 'app.rfeyeList')

        end


        function Fcn1_TableCreation(app, SelectedRow)

            set(app.UITable, 'Data', app.editedList(:, [1, 3, 5:7]), 'Selection', SelectedRow)            

            if ~isempty(app.UITable.StyleConfigurations)
                removeStyle(app.UITable)
            end

            DisableRows = find(~app.editedList.Enable);

            if ~isempty(DisableRows)
                s = uistyle('FontColor', [.75,.75,.75]);
                addStyle(app.UITable, s, 'row', DisableRows)
            end

            TableSelectionChanged(app)

        end


        function Fcn_SelectedRowValues(app)

            row = app.UITable.Selection;

            app.IP1.Value       = app.CallingApp.RFeyeList.IP1{row};
            app.IP2.Value       = app.CallingApp.RFeyeList.IP2{row};

            app.webPass.Data    = app.CallingApp.RFeyeList.webPass{row};
            app.rootPass.Data   = app.CallingApp.RFeyeList.rootPass{row};

            app.ftpPort.Value   = app.CallingApp.RFeyeList.ftpPort(row);
            app.sftpPort.Value  = app.CallingApp.RFeyeList.sftpPort(row);

            app.LOG.Value       = jsonencode(app.CallingApp.RFeyeList.LOG{row}, 'PrettyPrint', true);
            
        end

        
        function result = Fcn_IP(app)

            IP1Status = Fcn_IPValidation(app, app.IP1.Value);
            IP2Status = Fcn_IPValidation(app, app.IP2.Value);

            msg = '';
            if (~IP1Status && ~IP2Status) || (IP1Status + IP2Status == -2)
                msg = 'Endereços IP inválidos.';
            elseif ~IP1Status
                msg = 'Endereço IP do tipo "Público / Intranet" inválido.';
            elseif ~IP2Status
                msg = 'Endereço IP do tipo "Open VPN" inválido.';
            end

            if ~isempty(msg)
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', 'error')
            end

            if ~IP1Status || ~IP2Status || (IP1Status + IP2Status == -2)
                result = 0;
            else
                result = 1;
            end

        end
        

        function result = Fcn_IPValidation(app, IP)

            if isempty(IP)
                result = -1;

            else
                IP = strsplit(IP, '.');
                IP = cellfun(@(x) str2double(x), IP);
        
                if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                    result = 0;
                else
                    result = 1;
                end
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.webPass.HTMLSource  = '<!DOCTYPE html><html><head><script type="text/javascript">function setup(htmlComponent) {var dom = document.getElementById("pwrd");window.updateData = function (newData) {htmlComponent.Data = dom.value;};htmlComponent.addEventListener("DataChanged", function() {dom.value = htmlComponent.Data;});}</script></head><body><input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 106px; height: 16px"></input></body></html>';
            app.rootPass.HTMLSource = app.webPass.HTMLSource;

            app.rfeyeList  = Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'rfeyeList.cfg'));
            app.editedList = app.rfeyeList;

            set(app.UITable, 'Data', app.editedList(:, [1:4, 9:11]), 'Selection', 1)
            TableSelectionChanged(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                Fcn1_saveNewFile(app, fullfile(app.RootFolder, 'Settings'))

                app.CallingApp.rfeyeList = [];
                if app.CallingApp.Button_Logger.Value
                    app.CallingApp.Misc_winAux('winRFeyeEdit')
                end
            end

            app.CallingApp.auxWin4 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function TableSelectionChanged(app, event)
            
            if isempty(app.UITable.Selection)
                app.UITable.Selection = 1;
            end

            idx = app.UITable.Selection;

            app.IP1.Value = app.editedList.IP1{idx};
            app.IP2.Value = app.editedList.IP2{idx};

            app.Latitude.Value  = app.editedList.Latitude(idx);
            app.Longitude.Value = app.editedList.Longitude(idx);

            app.webLogin.Value  = 'admin';
            app.rootLogin.Value = 'root';

            app.LOG.Value       = jsonencode(app.editedList.LOG{idx}, "PrettyPrint", true);

            if app.Button_edit.Value
                if app.editedList.Enable(idx); app.Status.Value = 'ON';
                else;                          app.Status.Value = 'OFF';
                end

                app.ftpPort.Value  = app.editedList.ftpPort(idx);
                app.sftpPort.Value = app.editedList.sftpPort(idx);
                
            else
                if app.editedList.Enable(idx); app.Status.Items = {'ON'};
                else;                          app.Status.Items = {'OFF'};
                end

                app.ftpPort.Limits  = [app.editedList.ftpPort(idx),  app.editedList.ftpPort(idx)+.1 ];
                app.sftpPort.Limits = [app.editedList.sftpPort(idx), app.editedList.sftpPort(idx)+.1];
            end
            
        end

        % Callback function
        function Button1Pushed(app, event)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.RootFolder)
            end
            
            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ExceptionGlobalList.cfg".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b> - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button2Pushed(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            app.CallingApp.exceptionList(idx,:) = [];
            app.row = 1;

            Fcn1_TableStyle(app)
            app.UITable.Selection = [1, 1];
            Fcn_SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Callback function
        function Button3_Clicked(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            if ~isempty(idx)
                idx = idx(1);
            end

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value) || ...
               app.CallingApp.peaksTable.Station(app.row) ~= app.Station_edited.Value               || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                     || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)         || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel.Value)           || ...
               ~isempty(deblank(strjoin(app.Info.Value)))

                stationInfo = '';
                try
                    stationInfo = jsondecode(app.Info.Value{1});
                catch
                end
    
                if isstruct(stationInfo)
                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      app.Type.Value,                               ...
                                      app.RegInfo_edited.Value,                     ...
                                      str2double(stationInfo.Service),              ...
                                      str2double(stationInfo.Station),              ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      app.Compliance.Value,                         ...
                                      app.RiskLevel.Value};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.CallingApp.peaksTable.Service(app.row),      ...
                                                  app.CallingApp.peaksTable.Station(app.row),      ...
                                                  app.CallingApp.peaksTable.Description{app.row},  ...
                                                  app.CallingApp.peaksTable.Distance{app.row},     ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            else
                                if app.Station_edited.Value == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.Service_edited.Value,                        ...
                                                  app.Station_edited.Value,                        ...
                                                  app.Info.Value,                                  ...
                                                  Distance,                                        ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            end

                        otherwise
                            if isempty(deblank(strjoin(app.Info.Value)))
                                Misc_uialertCreation(app, 'warning', 'Campo "Informações adicionais" não pode ficar vazio.')
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                              app.CallingApp.peaksTable.Frequency(app.row),    ...
                                              app.Type.Value,                                  ...
                                              app.RegInfo_edited.Value,                        ...
                                              -1, -1, ['[EXC] ' strjoin(app.Info.Value)], '-', ...
                                              app.Compliance.Value,                            ...
                                              app.RiskLevel.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                Fcn1_TableStyle(app)            
                Fcn_SelectedRowValues(app)

            else
                Button2Pushed(app)
            end
            
            app.CallingApp.auxWin_SignalAnalysis
            
        end

        % Callback function
        function Station_editedValueChanged(app, event)
            
            latNode  = app.CallingApp.peaksTable.Latitude(app.row);
            longNode = app.CallingApp.peaksTable.Longitude(app.row);
            
            try
                StationInfo = anateldb_Query(app.Station_edited.Value, latNode, longNode, app.RootFolder);
                
                msg = sprintf(['<font style="font-size:12;">Indicação automática:\n• Frequência: %.3f MHz\n• Descrição: %s\n• Distância: %s\n\n'                      ...
                               'Indicação manual:    \n• Frequência(s): %s MHz\n• Descrição: %s\n• Distância: %.1f\n\n Confirma edição?</font>'], ...
                               app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row},              ...
                               app.CallingApp.peaksTable.Distance{app.row}, StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
    
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('Service', num2str(StationInfo.Service), ...
                                                             'Station', num2str(StationInfo.Station), ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0, 'BackgroundColor', [.80,.80,.80])

                else
                    app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Callback function
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.Station_edited, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.Info,    'Editable', 0, 'BackgroundColor', [.80,.80,.80], 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Station_edited, 'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', -1)
                    set(app.Info,    'Editable', 1, 'BackgroundColor', [1,1,1])
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Callback function
        function xLimFactor_DownClicked(app, event)
            
            if app.xLimFactor > .5
                app.xLimFactor = app.xLimFactor - .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function xLimFactor_UpClicked(app, event)
            
            if app.xLimFactor < 10
                app.xLimFactor = app.xLimFactor + .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end

        end

        % Callback function
        function ButtonPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winRFeyeEdit>\n'), sprintf('\n</winRFeyeEdit>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Button pushed function: Button_connect
        function Button_2Pushed(app, event)
            
            result = Fcn_IP(app);
            if result
                d = uiprogressdlg(app.UIFigure, "Message", "Em andamento...", "Indeterminate", "on");
    
                app.Status = Misc_RFeyeDatabase(app.RootFolder,                         ...
                                                app.IP1.Value, app.IP2.Value,           ...
                                                app.webLogin.Value, app.webPass.Data,   ...
                                                app.rootLogin.Value, app.rootPass.Data, ...
                                                app.ftpPort.Value, app.sftpPort.Value);
    
                if app.Status.ConnectFlag
                    app.Button_2.Enable = 1;

                    if isempty(app.Status.ID)
                        msg  = 'Não identificado o sensor sob análise.';
                        icon = 'warning';
                    else
                        if strcmp(app.Status.ID, app.Node.Value)
                            msg  = sprintf('Confirmado se tratar do sensor %s.', app.Status.ID); 
                            icon = 'success';
                        else
                            msg  = sprintf('Confirmado se tratar do sensor %s, diferente do selecionado (%s).', app.Status.ID, app.Node.Value);
                            icon = 'error'; 
                            app.Button_2.Enable = 0;
                        end
                    end
                    msg = sprintf('%s\n\n%s', msg, jsonencode(rmfield(app.Status, {'IP1', 'IP2', 'webPass', 'rootPass', 'ftpPort', 'sftpPort', 'ID', 'ConnectFlag', 'LogDate'})));
                else
                    msg = 'Sensor não respondeu às tentativas de conexão.';
                    icon = 'error'; 
                    
                    app.Lamp.Color = [1,0,0];
                    app.Button_2.Enable = 0;
                end
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', icon)

            end
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winRFeyeEdit>\n'), sprintf('\n</winRFeyeEdit>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Value changed function: Button_edit
        function Button_editValueChanged(app, event)
            value = app.Button_edit.Value;


        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
        end

        % Button pushed function: Button_export
        function Button_exportPushed(app, event)
            
        end

        % Button pushed function: Button_open
        function Button_openPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Grid4);
            app.Sublabel1_2 = uilabel(app.GridLayout8);
            app.Sublabel1_2.Text = 'Endereço(s) IP';
            app.Panel2 = uipanel(app.GridLayout8);
            app.Grid3 = uigridlayout(app.Panel2);
            app.IP2Label = uilabel(app.Grid3);
            app.IP2Label.Text = 'OpenVPN:';
            app.IP2 = uieditfield(app.Grid3, 'text');
            app.IP2.Editable = 'off';
            app.IP1Label = uilabel(app.Grid3);
            app.IP1Label.Text = 'Público / Intranet:';
            app.IP1 = uieditfield(app.Grid3, 'text');
            app.IP1.Editable = 'off';
            app.StatusLabel = uilabel(app.GridLayout8);
            app.StatusLabel.Text = 'Estado:';
            app.Status = uidropdown(app.GridLayout8);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.Value = 'ON';
            app.Sublabel3_2 = uilabel(app.GridLayout8);
            app.Sublabel3_2.Text = 'Coordenadas geográficas';
            app.Panel4_2 = uipanel(app.GridLayout8);
            app.Grid5_2 = uigridlayout(app.Panel4_2);
            app.LatitudeLabel = uilabel(app.Grid5_2);
            app.LatitudeLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.Grid5_2, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -1;
            app.LongitudeLabel = uilabel(app.Grid5_2);
            app.LongitudeLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.Grid5_2, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -1;
            app.GridLayout9 = uigridlayout(app.Grid4);
            app.Sublabel2_2 = uilabel(app.GridLayout9);
            app.Sublabel2_2.Text = 'Web';
            app.Panel3 = uipanel(app.GridLayout9);
            app.Grid4_3 = uigridlayout(app.Panel3);
            app.UsurioEditFieldLabel_2 = uilabel(app.Grid4_3);
            app.UsurioEditFieldLabel_2.Text = 'Usuário:';
            app.webLogin = uieditfield(app.Grid4_3, 'text');
            app.webLogin.Editable = 'off';
            app.webLogin.Value = 'admin';
            app.webPassLabel = uilabel(app.Grid4_3);
            app.webPassLabel.Text = 'Senha:';
            app.webPass = uihtml(app.Grid4_3);
            app.Sublabel3 = uilabel(app.GridLayout9);
            app.Sublabel3.Text = 'FTP/SFTP';
            app.Panel4 = uipanel(app.GridLayout9);
            app.Grid5 = uigridlayout(app.Panel4);
            app.UsurioEditFieldLabel = uilabel(app.Grid5);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.rootLogin = uieditfield(app.Grid5, 'text');
            app.rootLogin.Editable = 'off';
            app.rootLogin.Value = 'root';
            app.PortaSFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaSFTPSpinnerLabel.Text = 'Porta SFTP:';
            app.sftpPort = uispinner(app.Grid5);
            app.sftpPort.Limits = [0 65535];
            app.sftpPort.RoundFractionalValues = 'on';
            app.sftpPort.ValueDisplayFormat = '%.0f';
            app.sftpPort.Editable = 'off';
            app.sftpPort.Value = 2828;
            app.PortaFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaFTPSpinnerLabel.Text = 'Porta FTP:';
            app.ftpPort = uispinner(app.Grid5);
            app.ftpPort.Limits = [0 65535];
            app.ftpPort.RoundFractionalValues = 'on';
            app.ftpPort.ValueDisplayFormat = '%.0f';
            app.ftpPort.Editable = 'off';
            app.ftpPort.Value = 21;
            app.rootPassLabel = uilabel(app.Grid5);
            app.rootPassLabel.Text = 'Senha:';
            app.rootPass = uihtml(app.Grid5);
            app.GridLayout10 = uigridlayout(app.Grid4);
            app.LOGLabel = uilabel(app.GridLayout10);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uitextarea(app.GridLayout10);
            app.LOG.Editable = 'off';
            app.UITable = uitable(app.Grid1);
            app.UITable.ColumnName = {'ID'; 'Nome'; 'IP1'; 'IP2'; 'Latitude'; 'Longitude'; 'Localidade'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true false false false false false false true true];
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @TableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'Menu_Connect.png';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #000000; font-size: 24;">RFeye Node 20-6</b><font> INSTRUMENTOS</font>'; '<font>Edição de informações e teste de conectividade</font>'; ' '};
            app.Label2 = uilabel(app.Grid1);
            app.Label2.Text = 'MODO DE VISUALIZAÇÃO';
            app.GridLayout5_2 = uigridlayout(app.Grid1);
            app.Button_info = uibutton(app.GridLayout5_2, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'toolbar_info.png';
            app.Button_info.Text = '';
            app.Button_edit = uibutton(app.GridLayout5_2, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editValueChanged, true);
            app.Button_edit.Tooltip = {'Modo de edição'};
            app.Button_edit.Icon = 'edit.png';
            app.Button_edit.Text = '';
            app.Separator1 = uiimage(app.GridLayout5_2);
            app.Separator1.ImageSource = 'Line.png';
            app.Button_play = uibutton(app.GridLayout5_2, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'General_editWhite.png';
            app.Button_play.Enable = 'off';
            app.Button_play.Text = 'Confirma edição';
            app.Separator2 = uiimage(app.GridLayout5_2);
            app.Separator2.ImageSource = 'Line.png';
            app.Button_open = uibutton(app.GridLayout5_2, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Button_open.Icon = 'file.png';
            app.Button_open.Tooltip = {'Abrir'};
            app.Button_open.Text = '';
            app.Button_export = uibutton(app.GridLayout5_2, 'push');
            app.Button_export.ButtonPushedFcn = createCallbackFcn(app, @Button_exportPushed, true);
            app.Button_export.Icon = 'General_save.png';
            app.Button_export.Tooltip = {'Exportar'};
            app.Button_export.Text = '';
            app.Button_connect = uibutton(app.GridLayout5_2, 'push');
            app.Button_connect.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_connect.Icon = 'connectivity.png';
            app.Button_connect.Enable = 'off';
            app.Button_connect.Tooltip = {'Teste de conectividade'};
            app.Button_connect.Text = '';
            app.Panel_5 = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Panel_5);
            app.instrImage = uiimage(app.GridLayout2);
            app.instrImage.ImageSource = 'Instr_RFeye.png';
            app.instrTag = uilabel(app.GridLayout2);
            app.instrTag.Text = 'RFEYE NODE 20-6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFeyeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end