classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Grid1                    matlab.ui.container.GridLayout
        Button                   matlab.ui.control.Button
        Grid2                    matlab.ui.container.GridLayout
        Grid2d                   matlab.ui.container.GridLayout
        GPU_Panel                matlab.ui.container.Panel
        GPU_Grid                 matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        VisibilityLabel          matlab.ui.control.Label
        Visibility               matlab.ui.control.DropDown
        GPU_ResolutionLabel      matlab.ui.control.Label
        GPU_Resolution           matlab.ui.control.DropDown
        GPU_FormatLabel          matlab.ui.control.Label
        GPU_Format               matlab.ui.control.DropDown
        GPU_Label2               matlab.ui.control.Label
        GPU_Label1               matlab.ui.control.Label
        GPU_Type                 matlab.ui.control.DropDown
        GPU_Info                 matlab.ui.control.TextArea
        GPU_Button               matlab.ui.control.Button
        Grid2c                   matlab.ui.container.GridLayout
        Misc_Button1             matlab.ui.control.Button
        Misc_Panel               matlab.ui.container.Panel
        Misc_Grid                matlab.ui.container.GridLayout
        FileSize                 matlab.ui.control.Spinner
        FileSizeLabel            matlab.ui.control.Label
        HistoryDepth             matlab.ui.control.DropDown
        HistoryDepthLabel        matlab.ui.control.Label
        ColorMapLabel            matlab.ui.control.Label
        ColorMap                 matlab.ui.control.DropDown
        IntegrationFactor2Label  matlab.ui.control.Label
        IntegrationFactor2       matlab.ui.control.Spinner
        IntegrationFactor1Label  matlab.ui.control.Label
        IntegrationFactor1       matlab.ui.control.Spinner
        Misc_Label3              matlab.ui.control.Label
        Misc_Label2              matlab.ui.control.Label
        Misc_Label1              matlab.ui.control.Label
        Misc_Button2             matlab.ui.control.Button
        Grid2b                   matlab.ui.container.GridLayout
        Variables_Button         matlab.ui.control.Button
        Variables_Panel          matlab.ui.container.Panel
        Variables_Grid           matlab.ui.container.GridLayout
        Variables_Info2_2        matlab.ui.control.Label
        Variables_CheckBox1      matlab.ui.control.CheckBox
        Variables_Info2_3        matlab.ui.control.Label
        Variables_CheckBox3      matlab.ui.control.CheckBox
        Variables_Label          matlab.ui.control.Label
        Variables_Info2          matlab.ui.control.Label
        Variables_CheckBox2      matlab.ui.control.CheckBox
        Grid2a                   matlab.ui.container.GridLayout
        Info                     matlab.ui.control.TextArea
        Version_Toolbar1         matlab.ui.control.Button
        Version_Toolbar2         matlab.ui.control.Button
        Version_Toolbar3         matlab.ui.control.Button
        Version_Button           matlab.ui.control.Button
        Logo                     matlab.ui.control.Image
        Title                    matlab.ui.control.Label
        Tree                     matlab.ui.container.Tree
        Node1                    matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        TreeLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        newVersion
        PublicLinks

        ChangedItems
        
    end
    
    methods (Access = private)
        
        function Fcn1_UpdateVersion(app)
            
            app.CallingApp.General.Version = Misc_appVersions(app.RootFolder);
            Fcn2_VersionInfo(app)
            
        end


        function Fcn2_VersionInfo(app)
            
            app.Info.Value = sprintf('Informações acerca das versões instaladas dos módulos do appColeta:\n%s\n\n', ...
                                      jsonencode(app.CallingApp.General.Version, 'PrettyPrint', true));
            
            app.Version_Toolbar2.Enable = 0;
            app.Version_Toolbar3.Enable = 0;
            
        end
        
        
        function Fcn3_Graphics(app)

            graphRender = opengl('data');
            app.GPU_Info.Value = jsonencode(rmfield(graphRender, 'Extensions'), 'PrettyPrint', true);
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.GPU_Type.Value = 'Hardware Basic';
                case 'full';  app.GPU_Type.Value = 'Hardware Full';
                case 'none';  app.GPU_Type.Value = 'Software';
            end

            app.Visibility.Value = app.CallingApp.General.Image.Visibility;

            switch app.CallingApp.General.Image.Format
                case 'jpeg'; app.GPU_Format.Value = 'Joint Photographics Experts Group (JPEG)';
                case 'png';  app.GPU_Format.Value = 'Portable Network Graphics (PNG)';
            end

            app.GPU_Resolution.Value = num2str(app.CallingApp.General.Image.Resolution);
            app.GPU_Button.Enable    = 0;
            
        end


        function Fcn4_GeneralValues(app)
            
            app.FileSize.Value     = app.CallingApp.General.FileSize ./ 1e+6;
            app.ColorMap.Value     = app.CallingApp.General.ColorMap;
            app.HistoryDepth.Value = num2str(app.CallingApp.General.HistoryDepth);
            
            app.IntegrationFactor1.Value = app.CallingApp.General.AverageFactor;
            app.IntegrationFactor2.Value = app.CallingApp.General.SampleTimeFactor;
            
            app.Misc_Button2.Enable = 0;
            app.ChangedItems        = [];
            
        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            if isdeployed
                set(app.Variables_Grid.Children, 'Enable', 0)
            end
            
            expand(app.Tree, 'all')
            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)
            
            Fcn2_VersionInfo(app)
            Fcn3_Graphics(app)
            Fcn4_GeneralValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Value changed function: Variables_CheckBox1, 
        % Variables_CheckBox2, Variables_CheckBox3
        function CheckBoxes(app, event)
            
            if app.Variables_CheckBox1.Value || app.Variables_CheckBox2.Value || app.Variables_CheckBox3.Value || app.Variables_CheckBox4.Value
                app.Variables_Button.Enable = 1;
            else
                app.Variables_Button.Enable = 0;
            end
            
        end

        % Button pushed function: Version_Button
        function Version_ButtonPushed(app, event)
            
            try
                FileVersion = jsondecode(webread(fileread(fullfile(app.RootFolder, 'Settings', 'PublicLink.json'))));
                app.newVersion = struct('appColeta',  FileVersion.appColeta.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version, ...
                                        'anateldb',   FileVersion.anateldb);
                
                if isequal(rmfield(app.CallingApp.General.Version, {'Matlab', 'Python'}), app.newVersion)
                    Misc_uialertCreation(app, 'info', sprintf('Módulos atualizados:\n• appColeta, fiscaliza, anateldb'))
                    
                else
                    app.Info.Value{end+1} = sprintf('Informações acerca das versões disponíveis dos módulos do appColeta:\n%s\n\n%s\n\n', ...
                                                     jsonencode(app.newVersion, 'PrettyPrint', true));
                                    
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.General.Version.appColeta, app.newVersion.appColeta); str1(end+1) = {'appColeta'};
                    else;                                                                          str2(end+1) = {'appColeta'};
                    end                    
                    if strcmp(app.CallingApp.General.Version.fiscaliza, app.newVersion.fiscaliza); str1(end+1) = {'fiscaliza'};
                    else;                                                                          str2(end+1) = {'fiscaliza'}; app.Version_Toolbar2.Enable = 1;
                    end                    
                    if isequal(app.CallingApp.General.Version.anateldb, app.newVersion.anateldb);  str1(end+1) = {'anateldb'};
                    else;                                                                          str2(end+1) = {'anateldb'};  app.Version_Toolbar3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str2, ', '));
                    end

                    Misc_uialertCreation(app, 'warning', msg)
                    
                end
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Value changed function: ColorMap, FileSize, HistoryDepth, 
        % IntegrationFactor1, IntegrationFactor2
        function Misc_ValueChanged(app, event)

            switch event.Source
                case app.IntegrationFactor1
                    app.ChangedItems = unique([app.ChangedItems, 1]);

                case app.IntegrationFactor2
                    app.ChangedItems = unique([app.ChangedItems, 2]);

                case app.ColorMap
                    app.ChangedItems = unique([app.ChangedItems, 3]);

                case app.HistoryDepth
                    app.ChangedItems = unique([app.ChangedItems, 4]);

                case app.FileSize
                    app.ChangedItems = unique([app.ChangedItems, 5]);
            end

            app.Misc_Button2.Enable = 1;

        end

        % Button pushed function: Misc_Button1
        function Misc_Button1Pushed(app, event)
            
            if app.IntegrationFactor1.Value ~= 10
                app.IntegrationFactor1.Value = 10;
                app.ChangedItems = unique([app.ChangedItems, 1]);
            end

            if app.IntegrationFactor2.Value ~= 20
                app.IntegrationFactor2.Value = 20;
                app.ChangedItems = unique([app.ChangedItems, 2]);
            end

            if app.ColorMap.Value ~= "jet"
                app.ColorMap.Value = 'jet';
                app.ChangedItems = unique([app.ChangedItems, 3]);
            end

            if app.HistoryDepth.Value ~= "128"
                app.HistoryDepth.Value = '128';
                app.ChangedItems = unique([app.ChangedItems, 4]);
            end

            if app.FileSize.Value ~= 100
                app.FileSize.Value = 100;
                app.ChangedItems = unique([app.ChangedItems, 5]);
            end
            
            Misc_Button2Pushed(app)

        end

        % Button pushed function: Misc_Button2
        function Misc_Button2Pushed(app, event)
            
            if ismember(1, app.ChangedItems)
                app.CallingApp.General.AverageFactor    = app.IntegrationFactor1.Value;
            end

            if ismember(2, app.ChangedItems)
                app.CallingApp.General.SampleTimeFactor = app.IntegrationFactor2.Value;
            end

            if ismember(3, app.ChangedItems)
                app.CallingApp.General.ColorMap = app.ColorMap.Value;

                if app.CallingApp.plotFlag
                    colormap(app.CallingApp.axes2, app.ColorMap.Value);
                end
            end

            if ismember(4, app.ChangedItems)
                if ~app.CallingApp.plotFlag
                    app.CallingApp.General.HistoryDepth = str2double(app.HistoryDepth.Value);
                else
                    Misc_uialertCreation(app, 'info', 'Não é possível alterar a profundidade do Waterfall se estiver sendo apresentado o plot de alguma tarefa.')
                end
            end

            if ismember(5, app.ChangedItems)
                app.CallingApp.General.FileSize = app.FileSize.Value .* 1e+6;
            end

            Fcn4_GeneralValues(app)
            
        end

        % Button pushed function: Variables_Button
        function Variables_ButtonPushed(app, event)
            
            if app.Variables_CheckBox1.Value
                assignin('base', 'specInfo',   app.CallingApp.specInfo)
                openvar('specInfo')
            end
            
            if app.Variables_CheckBox2.Value
                assignin('base', 'specMatrix', app.CallingApp.specMatrix)
                openvar('specMatrix')
            end
            
            if app.Variables_CheckBox3.Value
                assignin('base', 'bandList',   app.CallingApp.bandList)
                openvar('bandList')
            end
            
        end

        % Button pushed function: GPU_Button
        function GPU_ButtonPushed(app, event)
            
            switch app.GPU_Type.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            switch app.GPU_Format.Value
                case 'Joint Photographics Experts Group (JPEG)'; Format = 'jpeg';
                case 'Portable Network Graphics (PNG)';          Format = 'png';
            end
            app.CallingApp.General.Image = struct('Visibility', app.Visibility.Value, 'Format', Format, 'Resolution', str2double(app.GPU_Resolution.Value));

            Fcn3_Graphics(app)
            
        end

        % Button pushed function: Version_Toolbar1
        function Version_Toolbar1Pushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            figure(app.UIFigure)
            
            if file
                try
                    pyenv('Version', fullfile(pathfile, file));
                    Misc_uialertCreation(app, 'info', 'Alteração realizada com sucesso!')
                    
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Verificando as versões dos módulos do appColeta...');
                    Fcn1_UpdateVersion(app)
                
                catch ME
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end
            
        end

        % Button pushed function: Version_Toolbar2
        function Version_Toolbar2Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a biblioteca Fiscaliza...');
            
            Python   = pyenv;
            [~, msg] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.newVersion.fiscaliza)));
            
            app.Info.Value{end+1} = sprintf('%s\n\n', msg);
            
            [~, fiscalizaVersion] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), 'list | findstr fiscaliza'));
            if ~isempty(fiscalizaVersion); app.CallingApp.General.Version.fiscaliza = strtrim(extractAfter(fiscalizaVersion, 'fiscaliza'));
            end
            
        end

        % Button pushed function: Version_Toolbar3
        function Version_Toolbar3Pushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a base de dados offline da Agência...');

                websave(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'), 'https://github.com/ronaldokun/anateldb/blob/master/dados/AnatelDB.parquet.gzip?raw=true');

                ParquetInfoFile = parquetinfo(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'));
                if ~isequal(ParquetInfoFile.VariableTypes(1:9), ["string", "double", "single", "single", "string", "int16", "int32", "categorical", "single"])
                    error('Ao menos uma das colunas da nova versão do anateldb não estão no formato esperado.')
                end
                
                if isfile(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                    delete(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.RootFolder, 'DataBase', 'Anatel.mat'), 'Anatel_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                clear global AnatelDB
                clear global AnatelDB_info
                drawnow nocallbacks
                
                anateldb_Read(app.RootFolder)
                
                global AnatelDB_info
                app.CallingApp.General.Version.anateldb = AnatelDB_info;
                
                Fcn1_UpdateVersion(app)
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

            delete(d)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            NodeData = app.Tree.SelectedNodes.NodeData;
            
            if ~isempty(NodeData)
                switch NodeData
                    case 1; app.Grid2.RowHeight = {'1x',0,0,0};
                    case 2; app.Grid2.RowHeight = {0,'1x',0,0};
                    case 3; app.Grid2.RowHeight = {0,0,'1x',0};
                    case 4; app.Grid2.RowHeight = {0,0,0,'1x'};
                end
                
            else
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end
            
        end

        % Value changed function: GPU_Format, GPU_Resolution, GPU_Type, 
        % Visibility
        function GPUTypeValueChanged(app, event)
            
            app.GPU_Button.Enable = 1;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winSettings>', '</winSettings>')));

            Misc_uialertCreation(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.TreeLabel = uilabel(app.Grid1);
            app.TreeLabel.Text = 'TIPO';
            app.Tree = uitree(app.Grid1);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node1 = uitreenode(app.Tree);
            app.Node1.NodeData = 1;
            app.Node1.Text = 'Geral';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.NodeData = 2;
            app.Node2.Text = 'Variáveis';
            app.Node3 = uitreenode(app.Tree);
            app.Node3.NodeData = 3;
            app.Node3.Text = 'Miscelâneas';
            app.Node4 = uitreenode(app.Tree);
            app.Node4.NodeData = 4;
            app.Node4.Text = 'Unidade gráfica';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Configurações</b>'; '<font>Ajustes gerais</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'toolbar_settings.png';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Grid2a = uigridlayout(app.Grid2);
            app.Version_Button = uibutton(app.Grid2a, 'push');
            app.Version_Button.ButtonPushedFcn = createCallbackFcn(app, @Version_ButtonPushed, true);
            app.Version_Button.Icon = 'General_RefreshWhite.png';
            app.Version_Button.Text = 'Verificar versões';
            app.Version_Toolbar3 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar3.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar3Pushed, true);
            app.Version_Toolbar3.Icon = 'winAddFiles_Sheet.png';
            app.Version_Toolbar3.Tooltip = {'Atualizar base de dados offline'};
            app.Version_Toolbar3.Text = '';
            app.Version_Toolbar2 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar2.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar2Pushed, true);
            app.Version_Toolbar2.Icon = 'winFiscaliza.png';
            app.Version_Toolbar2.Tooltip = {'Atualizar biblioteca Fiscaliza'};
            app.Version_Toolbar2.Text = '';
            app.Version_Toolbar1 = uibutton(app.Grid2a, 'push');
            app.Version_Toolbar1.ButtonPushedFcn = createCallbackFcn(app, @Version_Toolbar1Pushed, true);
            app.Version_Toolbar1.Icon = 'Settings_Python.png';
            app.Version_Toolbar1.Tooltip = {'Editar ambiente Python'};
            app.Version_Toolbar1.Text = '';
            app.Info = uitextarea(app.Grid2a);
            app.Info.Editable = 'off';
            app.Grid2b = uigridlayout(app.Grid2);
            app.Variables_Panel = uipanel(app.Grid2b);
            app.Variables_Grid = uigridlayout(app.Variables_Panel);
            app.Variables_CheckBox2 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox2.Text = 'app.specMatrix';
            app.Variables_Info2 = uilabel(app.Variables_Grid);
            app.Variables_Info2.Text = 'Trata-se de variável criada tanto no modo TAREFA SCPI quanto no modo TAREFA LOGGER, possuindo uma estrutura muito parecida com a variável app.specData do appAnálise e armazenando informações da faixa de frequência selecionada (metadados da monitoração, vetor de timestamp e matriz de níveis).';
            app.Variables_Label = uilabel(app.Variables_Grid);
            app.Variables_Label.Text = 'Exportar variáveis para o workspace base do Matlab:';
            app.Variables_CheckBox3 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox3.Text = 'app.bandList';
            app.Variables_Info2_3 = uilabel(app.Variables_Grid);
            app.Variables_Info2_3.Text = 'Trata-se de variável criada no modo TAREFA LOGGER, armazenando informações das faixas de frequência sob monitoração.';
            app.Variables_CheckBox1 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.Variables_CheckBox1.Text = 'app.specInfo';
            app.Variables_Info2_2 = uilabel(app.Variables_Grid);
            app.Variables_Info2_2.Text = 'Trata-se de variável criada no modo TAREFA SCPI, armazenando informações das faixas de frequência sob monitoração.';
            app.Variables_Button = uibutton(app.Grid2b, 'push');
            app.Variables_Button.ButtonPushedFcn = createCallbackFcn(app, @Variables_ButtonPushed, true);
            app.Variables_Button.Icon = 'General_play.png';
            app.Variables_Button.Enable = 'off';
            app.Variables_Button.Text = 'Exportar';
            app.Grid2c = uigridlayout(app.Grid2);
            app.Misc_Button2 = uibutton(app.Grid2c, 'push');
            app.Misc_Button2.ButtonPushedFcn = createCallbackFcn(app, @Misc_Button2Pushed, true);
            app.Misc_Button2.Icon = 'General_editWhite.png';
            app.Misc_Button2.Enable = 'off';
            app.Misc_Button2.Text = 'Alterar';
            app.Misc_Panel = uipanel(app.Grid2c);
            app.Misc_Grid = uigridlayout(app.Misc_Panel);
            app.Misc_Label1 = uilabel(app.Misc_Grid);
            app.Misc_Label1.Text = 'Relacionados aos fatores de integração:';
            app.Misc_Label2 = uilabel(app.Misc_Grid);
            app.Misc_Label2.Text = 'Relacionados ao waterfall:';
            app.Misc_Label3 = uilabel(app.Misc_Grid);
            app.Misc_Label3.Text = 'Relacionado ao tamanho dos arquivos:';
            app.IntegrationFactor1 = uispinner(app.Misc_Grid);
            app.IntegrationFactor1.Limits = [2 100];
            app.IntegrationFactor1.RoundFractionalValues = 'on';
            app.IntegrationFactor1.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor1.ValueChangedFcn = createCallbackFcn(app, @Misc_ValueChanged, true);
            app.IntegrationFactor1.Value = 10;
            app.IntegrationFactor1Label = uilabel(app.Misc_Grid);
            app.IntegrationFactor1Label.Text = 'Fator de integração para aferição do traço médio:';
            app.IntegrationFactor2 = uispinner(app.Misc_Grid);
            app.IntegrationFactor2.Limits = [2 100];
            app.IntegrationFactor2.RoundFractionalValues = 'on';
            app.IntegrationFactor2.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor2.ValueChangedFcn = createCallbackFcn(app, @Misc_ValueChanged, true);
            app.IntegrationFactor2.Value = 20;
            app.IntegrationFactor2Label = uilabel(app.Misc_Grid);
            app.IntegrationFactor2Label.Text = 'Fator de integração para aferição do tempo de escrita médio:';
            app.ColorMap = uidropdown(app.Misc_Grid);
            app.ColorMap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.ColorMap.ValueChangedFcn = createCallbackFcn(app, @Misc_ValueChanged, true);
            app.ColorMap.Value = 'jet';
            app.ColorMapLabel = uilabel(app.Misc_Grid);
            app.ColorMapLabel.Text = 'Estilo:';
            app.HistoryDepthLabel = uilabel(app.Misc_Grid);
            app.HistoryDepthLabel.Text = 'Profundidade:';
            app.HistoryDepth = uidropdown(app.Misc_Grid);
            app.HistoryDepth.Items = {'64', '128', '256', '512'};
            app.HistoryDepth.ValueChangedFcn = createCallbackFcn(app, @Misc_ValueChanged, true);
            app.HistoryDepth.Value = '128';
            app.FileSizeLabel = uilabel(app.Misc_Grid);
            app.FileSizeLabel.Text = 'Tamanho máximo de arquivo (MB):';
            app.FileSize = uispinner(app.Misc_Grid);
            app.FileSize.Step = 10;
            app.FileSize.Limits = [1 500];
            app.FileSize.ValueChangedFcn = createCallbackFcn(app, @Misc_ValueChanged, true);
            app.FileSize.Value = 100;
            app.Misc_Button1 = uibutton(app.Grid2c, 'push');
            app.Misc_Button1.ButtonPushedFcn = createCallbackFcn(app, @Misc_Button1Pushed, true);
            app.Misc_Button1.Icon = 'General_Refresh.png';
            app.Misc_Button1.Tooltip = {'Voltar às configurações iniciais'};
            app.Misc_Button1.Text = '';
            app.Grid2d = uigridlayout(app.Grid2);
            app.GPU_Button = uibutton(app.Grid2d, 'push');
            app.GPU_Button.ButtonPushedFcn = createCallbackFcn(app, @GPU_ButtonPushed, true);
            app.GPU_Button.Icon = 'General_editWhite.png';
            app.GPU_Button.Enable = 'off';
            app.GPU_Button.Text = 'Alterar';
            app.GPU_Panel = uipanel(app.Grid2d);
            app.GPU_Grid = uigridlayout(app.GPU_Panel);
            app.GPU_Info = uitextarea(app.GPU_Grid);
            app.GPU_Info.Editable = 'off';
            app.GPU_Type = uidropdown(app.GPU_Grid);
            app.GPU_Type.Items = {'Software', 'Hardware Full', 'Hardware Basic'};
            app.GPU_Type.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Type.Value = 'Hardware Basic';
            app.GPU_Label1 = uilabel(app.GPU_Grid);
            app.GPU_Label1.Text = 'Tipo de unidade de processamento gráfico:';
            app.GPU_Label2 = uilabel(app.GPU_Grid);
            app.GPU_Label2.Text = 'Imagem a ser incluída no relatório:';
            app.Panel = uipanel(app.GPU_Grid);
            app.GridLayout = uigridlayout(app.Panel);
            app.GPU_Format = uidropdown(app.GridLayout);
            app.GPU_Format.Items = {'Joint Photographics Experts Group (JPEG)', 'Portable Network Graphics (PNG)'};
            app.GPU_Format.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Format.Value = 'Joint Photographics Experts Group (JPEG)';
            app.GPU_FormatLabel = uilabel(app.GridLayout);
            app.GPU_FormatLabel.Text = 'Formato:';
            app.GPU_Resolution = uidropdown(app.GridLayout);
            app.GPU_Resolution.Items = {'100', '120', '150', '200'};
            app.GPU_Resolution.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.GPU_Resolution.Value = '120';
            app.GPU_ResolutionLabel = uilabel(app.GridLayout);
            app.GPU_ResolutionLabel.Text = 'Resolução (dpi):';
            app.Visibility = uidropdown(app.GridLayout);
            app.Visibility.Items = {'on', 'off'};
            app.Visibility.ValueChangedFcn = createCallbackFcn(app, @GPUTypeValueChanged, true);
            app.Visibility.Value = 'on';
            app.VisibilityLabel = uilabel(app.GridLayout);
            app.VisibilityLabel.Text = 'Visibilidade:';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end