classdef winTaskEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Grid0                   matlab.ui.container.GridLayout
        Label2                  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Sublabel1_2             matlab.ui.control.Label
        ChannelLabel            matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        Panel2                  matlab.ui.container.Panel
        Grid2                   matlab.ui.container.GridLayout
        GridLayout7             matlab.ui.container.GridLayout
        Warn                    matlab.ui.control.Image
        task_BandLabel          matlab.ui.control.Label
        Panel1A                 matlab.ui.container.Panel
        task_GridA3_2           matlab.ui.container.GridLayout
        BitsPerPoint            matlab.ui.control.DropDown
        BitsPerPointLabel       matlab.ui.control.Label
        DurationUnit            matlab.ui.control.DropDown
        Duration                matlab.ui.control.NumericEditField
        DurationLabel           matlab.ui.control.Label
        Name                    matlab.ui.control.EditField
        NameLabel               matlab.ui.control.Label
        Sublabel1               matlab.ui.control.Label
        TreeLabel               matlab.ui.control.Label
        Tree                    matlab.ui.container.Tree
        GridLayout6             matlab.ui.container.GridLayout
        downImg                 matlab.ui.control.Image
        upImg                   matlab.ui.control.Image
        task_PanelA1            matlab.ui.container.Panel
        task_GridA4             matlab.ui.container.GridLayout
        Trigger                 matlab.ui.control.DropDown
        TriggerLabel            matlab.ui.control.Label
        DescriptionLabel        matlab.ui.control.Label
        Description             matlab.ui.control.EditField
        RevisitTime             matlab.ui.control.NumericEditField
        RevisitTimeLabel        matlab.ui.control.Label
        LevelUnit               matlab.ui.control.DropDown
        LevelUnitLabel          matlab.ui.control.Label
        Detector                matlab.ui.control.DropDown
        DetectorLabel           matlab.ui.control.Label
        RFMode                  matlab.ui.control.DropDown
        RFModeLabel             matlab.ui.control.Label
        IntegrationFactor       matlab.ui.control.NumericEditField
        IntegrationFactorLabel  matlab.ui.control.Label
        TraceMode               matlab.ui.control.DropDown
        TraceModeLabel          matlab.ui.control.Label
        Resolution              matlab.ui.control.NumericEditField
        ResolutionLabel         matlab.ui.control.Label
        StepWidth               matlab.ui.control.NumericEditField
        StepWidthLabel          matlab.ui.control.Label
        FreqStop                matlab.ui.control.NumericEditField
        FreqStopLabel           matlab.ui.control.Label
        FreqStart               matlab.ui.control.NumericEditField
        FreqStartLabel          matlab.ui.control.Label
        ThreadID                matlab.ui.control.NumericEditField
        ThreadIDLabel           matlab.ui.control.Label
        Status                  matlab.ui.control.DropDown
        StatusLabel             matlab.ui.control.Label
        GridLayout5             matlab.ui.container.GridLayout
        Separator2              matlab.ui.control.Image
        Button_addBand          matlab.ui.control.Button
        Button_addTask          matlab.ui.control.Button
        Button_edit             matlab.ui.control.StateButton
        Button_play             matlab.ui.control.Button
        Button_del              matlab.ui.control.Button
        Button_export           matlab.ui.control.Button
        Button_open             matlab.ui.control.Button
        Separator1              matlab.ui.control.Image
        Button_info             matlab.ui.control.Button
        Title                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        taskList
        editedList

        editedFlag = false
        
    end
    
    methods (Access = private)

        function tempList = Fcn1_OpenFile(app, FilePath)

            [tempList, msg] = OpenFile_taskList(FilePath, app.RootFolder);

            if ~isempty(msg)
                MessageBox(app, 'warning', msg)
            end

        end


        function Fcn1_saveNewFile(app, Folder)
            
            try
                fileID = fopen(fullfile(Folder, 'taskList.json'), 'wt');
                fwrite(fileID, jsonencode(app.taskList, 'PrettyPrint', true));
                fclose(fileID);

               MessageBox(app, 'info', sprintf('Salvo o arquivo <b>taskList.json</b> na pasta "%s"', Folder))
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end
        

        function Fcn2_TreeBuilding(app, SelectedNode)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            for ii = 1:numel(app.editedList)
                Task = uitreenode(app.Tree, 'Text', app.editedList{ii}.Name, 'NodeData', ii, 'UserData', 1:numel(app.editedList{ii}.Band));

                for jj = 1:numel(app.editedList{ii}.Band)
                    uitreenode(Task, 'Text', Fcn2_TreeBuilding_Aux1(app, ii, jj), 'NodeData', ii, 'UserData', jj);
                end
            end

            if ~isempty(app.Tree.Children)
                if ~isempty(SelectedNode)
                    idx1 = SelectedNode(1);
                    idx2 = SelectedNode(2);
                else
                    idx1 = 1;
                    idx2 = 1;
                end

                expand(app.Tree.Children(idx1))

                if idx2 == -1
                    app.Tree.SelectedNodes = app.Tree.Children(idx1);
                else
                    app.Tree.SelectedNodes = app.Tree.Children(idx1).Children(idx2);
                end
                TreeSelectionChanged(app)
            else
                Fcn3_Layout(app)
            end

            Fcn2_TreeBuilding_Aux2(app)
        
        end


        function NodeText = Fcn2_TreeBuilding_Aux1(app, idx1, idx2)

            NodeText = sprintf('ThreadID %d: %.3f - %.3f MHz', app.editedList{idx1}.Band(idx2).ThreadID,          ...
                                                               app.editedList{idx1}.Band(idx2).FreqStart ./ 1e+6, ...
                                                               app.editedList{idx1}.Band(idx2).FreqStop  ./ 1e+6);

        end


        function Fcn2_TreeBuilding_Aux2(app)

            if ~isempty(app.Tree.StyleConfigurations)
                removeStyle(app.Tree)
            end

            DisableNodes = [];
            for ii = 1:numel(app.editedList)
                for jj = 1:numel(app.editedList{ii}.Band)
                    if ~app.editedList{ii}.Band(jj).Enable
                        DisableNodes = [DisableNodes, app.Tree.Children(ii).Children(jj)];
                    end
                end
            end

            if ~isempty(DisableNodes)
                s = uistyle('FontColor', [.75,.75,.75]);
                addStyle(app.Tree, s, 'node', DisableNodes)
            end                

        end


        function Fcn3_Layout(app)

            if isempty(app.Tree.SelectedNodes)
                app.Sublabel1_2.Value = '';
                set(app.task_GridA3_2.Children, 'Enable', 0)
                set(app.task_GridA4.Children, 'Enable', 0)
                set(app.GridLayout6.Children, 'Enable', 0)

                set(app.GridLayout5.Children, 'Enable', 0)
                app.Button_info.Enable = 1;
                app.Separator1.Enable  = 1;
                app.Button_open.Enable = 1;

            else
                set(app.task_GridA3_2.Children, 'Enable', 1)

                if numel(app.Tree.SelectedNodes.UserData) == 1
                    set(app.task_GridA4.Children, 'Enable', 1)
    
                elseif numel(app.Tree.SelectedNodes.UserData) > 1
                    set(app.task_GridA4.Children, 'Enable', 0)
                end
                
                Fcn4_EstimatedSize(app)
            end

        end


        function Fcn4_EstimatedSize(app)

            if app.Duration.Value == inf
                Size = Fcn4_EstimatedSize_Aux1(app, 24*3600);
                app.Sublabel1_2.Text = sprintf('<b style="color: #a2142f; font-size: 24;">%.3f</b><font style="font-size: 10;">MB/DIA</font>', Size/1e+6);

            else
                switch app.DurationUnit.Value
                    case 'min'; Duration_sec = app.Duration.Value * 60;
                    case 'hr';  Duration_sec = app.Duration.Value * 3600;
                end

                Size = Fcn4_EstimatedSize_Aux1(app, Duration_sec);
                app.Sublabel1_2.Text = sprintf('<b style="color: #a2142f; font-size: 24;">%.3f</b><font style="font-size: 10;">MB</font>', Size/1e+6);
            end

        end


        function Size = Fcn4_EstimatedSize_Aux1(app, Duration_sec)
            
            idx = app.Tree.SelectedNodes.NodeData;
            BytesPerSample = str2double(extractBefore(app.BitsPerPoint.Value, 'bits')) / 8;

            Size = 0;
            for jj = 1:numel(app.editedList{idx}.Band)
                if app.editedList{idx}.Band(jj).Enable
                    Span   = app.editedList{idx}.Band(jj).FreqStop - app.editedList{idx}.Band(jj).FreqStart;
                    Step   = app.editedList{idx}.Band(jj).StepWidth;
                    Points = ceil(Span/Step) + 1;
                    Time   = app.editedList{idx}.Band(jj).RevisitTime;                
    
                    Size = Size + (Duration_sec / Time) * Points * BytesPerSample;
                end
            end

        end


        function Fcn5_imgWarnVisibility(app)

            span = (app.FreqStop.Value - app.FreqStart.Value)*1e+6;

            if span <= 0
                app.Warn.Visible     = 1;
                app.StepWidth.Enable = 0;
            else
                app.Warn.Visible     = 0;
                app.StepWidth.Enable = 1;
            end

        end


        function Fcn6_TraceMode(app)

            switch app.TraceMode.Value
                case 'ClearWrite'
                    set(app.IntegrationFactor, 'Enable', 0, 'Value', 1)

                otherwise
                    app.IntegrationFactor.Enable = 1;

                    if app.IntegrationFactor.Value == 1
                        app.IntegrationFactor.Value = 3;
                    end
            end

        end


        function Fcn7_newThreadID(app)

            idx1 = app.Tree.SelectedNodes.NodeData;

            for ii = 1:numel(app.editedList{idx1}.Band)
                app.editedList{idx1}.Band(ii).ThreadID = ii;
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);

            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.taskList   = Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'taskList.json'));
            app.editedList = app.taskList;

            Fcn2_TreeBuilding(app, [])
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                Fcn1_saveNewFile(app, fullfile(app.RootFolder, 'Settings'))

                app.CallingApp.taskList = [];
                if app.CallingApp.menu_Button2.Value
                    app.CallingApp.Misc_winAux('winTaskEdit');
                end
            end

            app.CallingApp.auxWin2 = [];
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            
            collapse(app.Tree)
            expand(app.Tree.Children(idx1))

            app.Name.Value         = app.editedList{idx1}.Name;
            app.BitsPerPoint.Items = {sprintf('%d bits', app.editedList{idx1}.BitsPerSample)};
            
            Duration_sec = app.editedList{idx1}.Duration;
            if Duration_sec >= 3600
                app.Duration.Value     = round(Duration_sec ./ 3600, 3);
                app.DurationUnit.Items = {'hr'};
            else
                app.Duration.Value     = round(Duration_sec ./ 60, 3);
                app.DurationUnit.Items = {'min'};
            end

            Fcn3_Layout(app)

            if numel(app.Tree.SelectedNodes.UserData) == 1
                idx2 = app.Tree.SelectedNodes.UserData;
    
                app.ThreadID.Value          = app.editedList{idx1}.Band(idx2).ThreadID;
                app.Description.Value       = app.editedList{idx1}.Band(idx2).Description;
                app.FreqStart.Value         = app.editedList{idx1}.Band(idx2).FreqStart  / 1e+6;
                app.FreqStop.Value          = app.editedList{idx1}.Band(idx2).FreqStop   / 1e+6;
                app.StepWidth.Value         = app.editedList{idx1}.Band(idx2).StepWidth  / 1e+3;
                app.Resolution.Value        = app.editedList{idx1}.Band(idx2).Resolution / 1e+3;                
                app.IntegrationFactor.Value = app.editedList{idx1}.Band(idx2).IntegrationFactor;
                app.RevisitTime.Value       = app.editedList{idx1}.Band(idx2).RevisitTime;

                if app.Button_edit.Value
                    if app.editedList{idx1}.Band(idx2).Enable; app.Status.Value = 'ON';
                    else;                                      app.Status.Value = 'OFF';
                    end

                    switch app.editedList{idx1}.Band(idx2).Trigger
                        case 0; app.Trigger.Value = 'OFF';
                        case 1; app.Trigger.Value = 'Máscara espectral';
                    end

                    app.TraceMode.Value     = app.editedList{idx1}.Band(idx2).TraceMode;
                    app.RFMode.Value        = app.editedList{idx1}.Band(idx2).RFMode;
                    app.Detector.Value      = app.editedList{idx1}.Band(idx2).Detector;
                    app.LevelUnit.Value     = app.editedList{idx1}.Band(idx2).LevelUnit;

                else
                    if app.editedList{idx1}.Band(idx2).Enable; app.Status.Items = {'ON'};
                    else;                                      app.Status.Items = {'OFF'};
                    end

                    switch app.editedList{idx1}.Band(idx2).Trigger
                        case 0; app.Trigger.Items = {'OFF'};
                        case 1; app.Trigger.Items = {'Máscara espectral'};
                    end

                    app.TraceMode.Items     = {app.editedList{idx1}.Band(idx2).TraceMode};
                    app.RFMode.Items        = {app.editedList{idx1}.Band(idx2).RFMode};
                    app.Detector.Items      = {app.editedList{idx1}.Band(idx2).Detector};
                    app.LevelUnit.Items     = {app.editedList{idx1}.Band(idx2).LevelUnit};
                end

                if app.TraceMode.Value == "ClearWrite"
                    app.IntegrationFactor.Enable = 0;
                else
                    app.IntegrationFactor.Enable = 1;
                end
            end

            if app.Button_edit.Value
                app.DurationUnit.Items = {'min', 'hr'};
                app.BitsPerPoint.Items = {'8 bits', '16 bits', '32 bits'};
            end
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winTaskEdit>', '</winTaskEdit>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_open
        function Button_openPushed(app, event)
            
            [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
                tempList = Fcn1_OpenFile(app, fullfile(Folder, File));

                if ~isempty(tempList)
                    app.taskList   = [app.taskList, tempList];
                    app.editedList = app.taskList;
                    app.editedFlag = true;

                    Fcn2_TreeBuilding(app, [])
                end
            end

        end

        % Button pushed function: Button_export
        function Button_exportPushed(app, event)
            
            Folder = uigetdir(app.CallingApp.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de tarefas');
            figure(app.UIFigure)

            if Folder
                Fcn1_saveNewFile(app, Folder)
            end

        end

        % Value changed function: Button_edit
        function Button_editValueChanged(app, event)
            
            if app.Button_edit.Value
                app.Label2.Text = 'MODO DE EDIÇÃO';

                set(app.Name,              'Editable', 1)
                set(app.Duration,          'Editable', 1)
                set(app.Description,       'Editable', 1)
                set(app.FreqStart,         'Editable', 1)
                set(app.FreqStop,          'Editable', 1)
                set(app.StepWidth,         'Editable', 1)
                set(app.Resolution,        'Editable', 1)
                set(app.IntegrationFactor, 'Editable', 1)
                set(app.RevisitTime,       'Editable', 1)

                set(app.DurationUnit, 'Items', {'min', 'hr'})
                set(app.BitsPerPoint, 'Items', {'8 bits', '16 bits', '32 bits'})
                set(app.Status,       'Items', {'ON', 'OFF'})
                set(app.Trigger,      'Items', {'OFF', 'Máscara espectral'})
                set(app.RFMode,       'Items', {'High Sensitivity', 'Normal', 'Low Distortion'})
                set(app.TraceMode,    'Items', {'ClearWrite', 'Average', 'MaxHold', 'MinHold'})
                set(app.Detector,     'Items', {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'})
                set(app.LevelUnit,    'Items', {'dBm', 'dBμV'})

                set(app.GridLayout6.Children, 'Enable', 1)
                
                set(app.GridLayout5.Children, 'Enable', 1)
                app.Button_open.Enable = 0;
                app.Button_export.Enable = 0;

            else
                app.Label2.Text = 'MODO DE VISUALIZAÇÃO';

                set(app.Name,              'Editable', 0)
                set(app.Duration,          'Editable', 0)
                set(app.Description,       'Editable', 0)
                set(app.FreqStart,         'Editable', 0)
                set(app.FreqStop,          'Editable', 0)
                set(app.StepWidth,         'Editable', 0)
                set(app.Resolution,        'Editable', 0)
                set(app.IntegrationFactor, 'Editable', 0)
                set(app.RevisitTime,       'Editable', 0)

                set(app.DurationUnit, 'Items', {app.DurationUnit.Value})
                set(app.BitsPerPoint, 'Items', {app.BitsPerPoint.Value})
                set(app.Status,       'Items', {app.Status.Value})
                set(app.Trigger,      'Items', {app.Trigger.Value})
                set(app.RFMode,       'Items', {app.RFMode.Value})
                set(app.TraceMode,    'Items', {app.TraceMode.Value})
                set(app.Detector,     'Items', {app.Detector.Value})
                set(app.LevelUnit,    'Items', {app.LevelUnit.Value})

                set(app.GridLayout6.Children, 'Enable', 0)
                
                set(app.GridLayout5.Children, 'Enable', 1)
                app.Button_addTask.Enable = 0;
                app.Button_addBand.Enable = 0;
                app.Button_del.Enable     = 0;
                app.Button_play.Enable    = 0;

                if ~isequal(app.taskList, app.editedList)
                    app.editedList = app.taskList;
                    Fcn2_TreeBuilding(app, [])
                end
            end

        end

        % Button pushed function: Button_addTask
        function Button_addTaskPushed(app, event)
            
            idx1 = numel(app.editedList) + 1;
            idx2 = 1;

            app.editedList{idx1} = struct('Name', 'Nova tarefa', ...
                                          'BitsPerSample', 8,    ...
                                          'Duration', 600,       ...
                                          'Band', struct('ThreadID', 1,                 ...
                                                         'Description', 'Faixa 1 de 1', ...
                                                         'FreqStart', 88e+6,            ...
                                                         'FreqStop', 108e+6,            ...
                                                         'StepWidth', 50e+3,            ...
                                                         'Resolution', 100e+3,          ...
                                                         'RFMode', 'Normal',            ...
                                                         'TraceMode', 'ClearWrite',     ...
                                                         'Detector', 'Average/RMS',     ...
                                                         'LevelUnit', 'dBm',            ...
                                                         'RevisitTime', 1,              ...
                                                         'IntegrationFactor', 1,        ...
                                                         'Trigger', 1,                  ...
                                                         'Enable', 1));

            Fcn2_TreeBuilding(app, [idx1, idx2])

        end

        % Button pushed function: Button_addBand
        function Button_addBandValueChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            
            idx2_old = app.Tree.SelectedNodes.UserData;
            idx2_new = numel(app.editedList{idx1}.Band) + 1;

            if app.Tree.SelectedNodes.Parent == app.Tree
                app.editedList{idx1}.Band(idx2_new) = app.editedList{idx1}.Band(1);
            else
                app.editedList{idx1}.Band(idx2_new) = app.editedList{idx1}.Band(idx2_old);
            end
             app.editedList{idx1}.Band(idx2_new).ThreadID = idx2_new;
            
            Fcn2_TreeBuilding(app, [idx1, idx2_new])

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;
            
            if app.Tree.SelectedNodes.Parent == app.Tree
                if numel(app.editedList) > 1
                    app.editedList(idx1) = [];
                    Fcn2_TreeBuilding(app, [1, -1])

                else
                    MessageBox(app, 'warning', 'Não é possível excluir a única tarefa.')
                    return
                end

            else
                if numel(app.editedList{idx1}.Band) > 1
                    app.editedList{idx1}.Band(idx2) = [];
                    Fcn7_newThreadID(app)
                    Fcn2_TreeBuilding(app, [idx1, 1])

                else
                    MessageBox(app, 'warning', 'Não é possível excluir a única faixa de frequência da tarefa.')
                    return
                end
            end            

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            if ~isequal(app.taskList, app.editedList)
                app.taskList   = app.editedList;
                app.editedFlag = true;
            end
            
            app.Button_edit.Value = 0;
            Button_editValueChanged(app)

        end

        % Value changed function: BitsPerPoint, Description, Detector, 
        % Duration, DurationUnit, FreqStart, FreqStop, IntegrationFactor, 
        % LevelUnit, Name, RFMode, Resolution, RevisitTime, Status, 
        % StepWidth, TraceMode, Trigger
        function TaskParameterChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;

            switch event.Source
                case app.Name
                    app.editedList{idx1}.Name    = app.Name.Value;
                    app.Tree.Children(idx1).Text = app.Name.Value;

                case {app.Duration, app.DurationUnit}
                    switch app.DurationUnit.Value
                        case 'min'; app.editedList{idx1}.Duration = app.Duration.Value * 60;
                        case 'hr';  app.editedList{idx1}.Duration = app.Duration.Value * 3600;
                    end
                    Fcn4_EstimatedSize(app)
                
                case app.BitsPerPoint
                    app.editedList{idx1}.BitsPerSample = str2double(extractBefore(app.BitsPerPoint.Value, 'bits'));
                    Fcn4_EstimatedSize(app)

                case app.Status
                    if numel(app.editedList{idx1}.Band) == 1
                        app.Status.Value = "ON";
                        MessageBox(app, 'warning', 'Tarefa com apenas uma única faixa de frequência não pode ter essa faixa com o estado "OFF".')
                        return

                    else
                        if app.Status.Value == "ON"; app.editedList{idx1}.Band(idx2).Enable = 1;
                        else;                        app.editedList{idx1}.Band(idx2).Enable = 0;
                        end
    
                        Enable = 0;
                        for ii = 1:numel(app.editedList{idx1}.Band)
                            if app.editedList{idx1}.Band(ii).Enable
                                Enable = 1;
                                break
                            end
                        end
    
                        if ~Enable
                            app.editedList{idx1}.Band(1).Enable = 1;
                            if idx2 == 1
                                app.Status.Value = "ON";
                                MessageBox(app, 'warning', 'Toda tarefa deve possuir ao menos uma faixa de frequência com o estado "ON".')
                                return
                            end
                        end
    
                        Fcn2_TreeBuilding_Aux2(app)
                        Fcn4_EstimatedSize(app)
                    end

                case app.Trigger
                    switch app.Trigger.Value
                        case 'OFF';               app.editedList{idx1}.Band(idx2).Trigger = 0;
                        case 'Máscara espectral'; app.editedList{idx1}.Band(idx2).Trigger = 1;
                    end

                case app.Description
                    app.editedList{idx1}.Band(idx2).Description = app.Description.Value;

                case app.FreqStart
                    app.editedList{idx1}.Band(idx2).FreqStart   = app.FreqStart.Value * 1e+6;
                    app.Tree.Children(idx1).Children(idx2).Text = Fcn2_TreeBuilding_Aux1(app, idx1, idx2);
                    Fcn5_imgWarnVisibility(app)
                    
                case app.FreqStop
                    app.editedList{idx1}.Band(idx2).FreqStop    = app.FreqStop.Value * 1e+6;
                    app.Tree.Children(idx1).Children(idx2).Text = Fcn2_TreeBuilding_Aux1(app, idx1, idx2);
                    Fcn5_imgWarnVisibility(app)

                case app.StepWidth
                    app.editedList{idx1}.Band(idx2).StepWidth = app.StepWidth.Value * 1e+3;

                case app.Resolution
                    app.editedList{idx1}.Band(idx2).Resolution = app.Resolution.Value * 1e+3;

                case app.TraceMode
                    app.editedList{idx1}.Band(idx2).TraceMode = app.TraceMode.Value;
                    Fcn6_TraceMode(app)

                    app.editedList{idx1}.Band(idx2).IntegrationFactor = app.IntegrationFactor.Value;

                case app.IntegrationFactor
                    app.editedList{idx1}.Band(idx2).IntegrationFactor = app.IntegrationFactor.Value;

                case app.RFMode
                    app.editedList{idx1}.Band(idx2).RFMode = app.RFMode.Value;

                case app.Detector
                    app.editedList{idx1}.Band(idx2).Detector = app.Detector.Value;

                case app.LevelUnit
                    app.editedList{idx1}.Band(idx2).LevelUnit = app.LevelUnit.Value;

                case app.RevisitTime
                    app.editedList{idx1}.Band(idx2).RevisitTime = app.RevisitTime.Value;
            end
            
        end

        % Image clicked function: downImg, upImg
        function UpDownImageClicked(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;

            Flag = 0;

            switch event.Source
                case app.upImg
                    if app.Tree.SelectedNodes.Parent == app.Tree
                        if idx1 > 1
                            app.editedList(idx1-1:idx1) = flip(app.editedList(idx1-1:idx1));

                            Flag = 1;
                            idx1 = idx1-1;
                        end
                    else
                        if idx2 > 1
                            app.editedList{idx1}.Band(idx2-1:idx2) = flip(app.editedList{idx1}.Band(idx2-1:idx2));
                            Fcn7_newThreadID(app)

                            Flag = 1;
                            idx2 = idx2-1;
                        end
                    end

                case app.downImg
                    if app.Tree.SelectedNodes.Parent == app.Tree
                        if idx1 < numel(app.editedList)
                            app.editedList(idx1:idx1+1) = flip(app.editedList(idx1:idx1+1));

                            Flag = 1;
                            idx1 = idx1+1;
                        end
                    else
                        if idx2 < numel(app.editedList{idx1}.Band)
                            app.editedList{idx1}.Band(idx2:idx2+1) = flip(app.editedList{idx1}.Band(idx2:idx2+1));
                            Fcn7_newThreadID(app)

                            Flag = 1;
                            idx2 = idx2+1;
                        end
                    end
            end

            if Flag
                if app.Tree.SelectedNodes.Parent == app.Tree
                    Fcn2_TreeBuilding(app, [idx1, -1])
                else
                    Fcn2_TreeBuilding(app, [idx1, idx2])
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid0 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid0);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #000000; font-size: 24;">Tarefa SCPI</b><font style="font-size: 10;"> PARAMETRIZAÇÃO</font>'; '<font>Criação/edição de <i>scripts</i> de tarefas regulares</font>'; ' '};
            app.GridLayout5 = uigridlayout(app.Grid0);
            app.Button_info = uibutton(app.GridLayout5, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Text = '';
            app.Separator1 = uiimage(app.GridLayout5);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = 'LT_LineV.png';
            app.Button_open = uibutton(app.GridLayout5, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Button_open.Icon = 'LT_OpenFile.png';
            app.Button_open.Tooltip = {'Abrir'};
            app.Button_open.Text = '';
            app.Button_export = uibutton(app.GridLayout5, 'push');
            app.Button_export.ButtonPushedFcn = createCallbackFcn(app, @Button_exportPushed, true);
            app.Button_export.Icon = 'LT_save.png';
            app.Button_export.Tooltip = {'Exportar'};
            app.Button_export.Text = '';
            app.Button_del = uibutton(app.GridLayout5, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'LT_redX.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Tooltip = {'Excluir tarefa ou faixa'};
            app.Button_del.Text = '';
            app.Button_play = uibutton(app.GridLayout5, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'DT_edit.png';
            app.Button_play.Enable = 'off';
            app.Button_play.Text = 'Confirma edição';
            app.Button_edit = uibutton(app.GridLayout5, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editValueChanged, true);
            app.Button_edit.Tooltip = {'Modo de edição'};
            app.Button_edit.Icon = 'LT_edit.png';
            app.Button_edit.Text = '';
            app.Button_addTask = uibutton(app.GridLayout5, 'push');
            app.Button_addTask.ButtonPushedFcn = createCallbackFcn(app, @Button_addTaskPushed, true);
            app.Button_addTask.Icon = 'LT_newTask.png';
            app.Button_addTask.Enable = 'off';
            app.Button_addTask.Tooltip = {'Adicionar tarefa'};
            app.Button_addTask.Text = '';
            app.Button_addBand = uibutton(app.GridLayout5, 'push');
            app.Button_addBand.ButtonPushedFcn = createCallbackFcn(app, @Button_addBandValueChanged, true);
            app.Button_addBand.Icon = 'LT_newEdit.png';
            app.Button_addBand.Enable = 'off';
            app.Button_addBand.Tooltip = {'Adicionar faixa'};
            app.Button_addBand.Text = '';
            app.Separator2 = uiimage(app.GridLayout5);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = 'LT_LineV.png';
            app.Panel2 = uipanel(app.Grid0);
            app.Grid2 = uigridlayout(app.Panel2);
            app.task_PanelA1 = uipanel(app.Grid2);
            app.task_GridA4 = uigridlayout(app.task_PanelA1);
            app.StatusLabel = uilabel(app.task_GridA4);
            app.StatusLabel.Text = 'Estado:';
            app.Status = uidropdown(app.task_GridA4);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Status.Value = 'ON';
            app.ThreadIDLabel = uilabel(app.task_GridA4);
            app.ThreadIDLabel.Text = 'ThreadID';
            app.ThreadID = uieditfield(app.task_GridA4, 'numeric');
            app.ThreadID.Limits = [1 Inf];
            app.ThreadID.RoundFractionalValues = 'on';
            app.ThreadID.ValueDisplayFormat = '%.0f';
            app.ThreadID.Editable = 'off';
            app.ThreadID.Value = 1;
            app.FreqStartLabel = uilabel(app.task_GridA4);
            app.FreqStartLabel.Text = 'Frequência inicial (MHz):';
            app.FreqStart = uieditfield(app.task_GridA4, 'numeric');
            app.FreqStart.Limits = [0 Inf];
            app.FreqStart.ValueDisplayFormat = '%.6f';
            app.FreqStart.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FreqStart.Editable = 'off';
            app.FreqStart.Value = 108;
            app.FreqStopLabel = uilabel(app.task_GridA4);
            app.FreqStopLabel.Text = 'Frequência final (MHz):';
            app.FreqStop = uieditfield(app.task_GridA4, 'numeric');
            app.FreqStop.Limits = [0 Inf];
            app.FreqStop.ValueDisplayFormat = '%.6f';
            app.FreqStop.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FreqStop.Editable = 'off';
            app.FreqStop.Value = 108;
            app.StepWidthLabel = uilabel(app.task_GridA4);
            app.StepWidthLabel.Text = 'Passo varredura (kHz):';
            app.StepWidth = uieditfield(app.task_GridA4, 'numeric');
            app.StepWidth.Limits = [0 Inf];
            app.StepWidth.ValueDisplayFormat = '%.3f';
            app.StepWidth.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.StepWidth.Editable = 'off';
            app.ResolutionLabel = uilabel(app.task_GridA4);
            app.ResolutionLabel.Text = 'Resolução (kHz):';
            app.Resolution = uieditfield(app.task_GridA4, 'numeric');
            app.Resolution.Limits = [0 Inf];
            app.Resolution.ValueDisplayFormat = '%.3f';
            app.Resolution.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Resolution.Editable = 'off';
            app.TraceModeLabel = uilabel(app.task_GridA4);
            app.TraceModeLabel.Text = 'Modo do traço:';
            app.TraceMode = uidropdown(app.task_GridA4);
            app.TraceMode.Items = {'ClearWrite', 'Average', 'MaxHold', 'MinHold'};
            app.TraceMode.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.TraceMode.Value = 'Average';
            app.IntegrationFactorLabel = uilabel(app.task_GridA4);
            app.IntegrationFactorLabel.Text = 'Fator de integração:';
            app.IntegrationFactor = uieditfield(app.task_GridA4, 'numeric');
            app.IntegrationFactor.Limits = [1 Inf];
            app.IntegrationFactor.RoundFractionalValues = 'on';
            app.IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.IntegrationFactor.Editable = 'off';
            app.IntegrationFactor.Value = 1;
            app.RFModeLabel = uilabel(app.task_GridA4);
            app.RFModeLabel.Text = 'Modo de RF:';
            app.RFMode = uidropdown(app.task_GridA4);
            app.RFMode.Items = {'High Sensitivity', 'Low Distortion', 'Normal'};
            app.RFMode.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.RFMode.Value = 'High Sensitivity';
            app.DetectorLabel = uilabel(app.task_GridA4);
            app.DetectorLabel.Text = 'Detector:';
            app.Detector = uidropdown(app.task_GridA4);
            app.Detector.Items = {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'};
            app.Detector.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Detector.Value = 'Sample';
            app.LevelUnitLabel = uilabel(app.task_GridA4);
            app.LevelUnitLabel.Text = 'Unidade:';
            app.LevelUnit = uidropdown(app.task_GridA4);
            app.LevelUnit.Items = {'dBm', 'dBμV'};
            app.LevelUnit.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.LevelUnit.Value = 'dBm';
            app.RevisitTimeLabel = uilabel(app.task_GridA4);
            app.RevisitTimeLabel.Text = 'Tempo revisita (seg):';
            app.RevisitTime = uieditfield(app.task_GridA4, 'numeric');
            app.RevisitTime.Limits = [0.001 Inf];
            app.RevisitTime.ValueDisplayFormat = '%.3f';
            app.RevisitTime.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.RevisitTime.Editable = 'off';
            app.RevisitTime.Value = 1;
            app.Description = uieditfield(app.task_GridA4, 'text');
            app.Description.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Description.Editable = 'off';
            app.DescriptionLabel = uilabel(app.task_GridA4);
            app.DescriptionLabel.Text = 'Descrição:';
            app.TriggerLabel = uilabel(app.task_GridA4);
            app.TriggerLabel.Text = 'Trigger:';
            app.Trigger = uidropdown(app.task_GridA4);
            app.Trigger.Items = {'OFF', 'Máscara espectral'};
            app.Trigger.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Trigger.Value = 'OFF';
            app.GridLayout6 = uigridlayout(app.Grid2);
            app.upImg = uiimage(app.GridLayout6);
            app.upImg.ImageClickedFcn = createCallbackFcn(app, @UpDownImageClicked, true);
            app.upImg.Enable = 'off';
            app.upImg.ImageSource = 'LT_upArrow_Zoom.png';
            app.downImg = uiimage(app.GridLayout6);
            app.downImg.ImageClickedFcn = createCallbackFcn(app, @UpDownImageClicked, true);
            app.downImg.Enable = 'off';
            app.downImg.ImageSource = 'LT_downArrow_Zoom.png';
            app.Tree = uitree(app.Grid2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TreeLabel = uilabel(app.Grid2);
            app.TreeLabel.Text = 'Tarefa(s):';
            app.Sublabel1 = uilabel(app.Grid2);
            app.Sublabel1.Text = 'Aspectos gerais:';
            app.Panel1A = uipanel(app.Grid2);
            app.task_GridA3_2 = uigridlayout(app.Panel1A);
            app.NameLabel = uilabel(app.task_GridA3_2);
            app.NameLabel.Text = 'Nome:';
            app.Name = uieditfield(app.task_GridA3_2, 'text');
            app.Name.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Name.Editable = 'off';
            app.DurationLabel = uilabel(app.task_GridA3_2);
            app.DurationLabel.Text = 'Duração:';
            app.Duration = uieditfield(app.task_GridA3_2, 'numeric');
            app.Duration.Limits = [0 Inf];
            app.Duration.ValueDisplayFormat = '%.3f';
            app.Duration.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Duration.Editable = 'off';
            app.Duration.Value = 10;
            app.DurationUnit = uidropdown(app.task_GridA3_2);
            app.DurationUnit.Items = {'min', 'hr'};
            app.DurationUnit.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.DurationUnit.Value = 'min';
            app.BitsPerPointLabel = uilabel(app.task_GridA3_2);
            app.BitsPerPointLabel.Text = 'Codificação:';
            app.BitsPerPoint = uidropdown(app.task_GridA3_2);
            app.BitsPerPoint.Items = {'8 bits', '16 bits', '32 bits'};
            app.BitsPerPoint.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.BitsPerPoint.Value = '8 bits';
            app.GridLayout7 = uigridlayout(app.Grid2);
            app.task_BandLabel = uilabel(app.GridLayout7);
            app.task_BandLabel.Text = 'Faixa de frequência selecionada:';
            app.Warn = uiimage(app.GridLayout7);
            app.Warn.Visible = 'off';
            app.Warn.ImageSource = 'LT_redWarn.png';
            app.Image = uiimage(app.Grid0);
            app.Image.ImageSource = 'LT_edit.png';
            app.Panel = uipanel(app.Grid0);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'ESTIMATIVA DE ESCRITA EM ARQUIVO';
            app.Sublabel1_2 = uilabel(app.GridLayout2);
            app.Sublabel1_2.Interpreter = 'html';
            app.Sublabel1_2.Text = '';
            app.Label2 = uilabel(app.Grid0);
            app.Label2.Text = 'MODO DE VISUALIZAÇÃO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTaskEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end